classdef Decawave_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        DecawaveSwitch  matlab.ui.control.RockerSwitch
    end

    
    methods (Access = private)
        
        function start_decawave_acquisition(app)
            global SPort
            if strcmp(app.DecawaveSwitch.Value,'Off')
                stop_decawave_acquisition(app);
            end
            % Toggle the pin to low, print the coordinates
            writeline(SPort.s,"gt 14");   readline(SPort.s);    readline(SPort.s);    %Toggle Output Pin
            configureTerminator(SPort.s,95,"LF");                         %Change terminator to "_"
            writeline(SPort.s,"les");     readline(SPort.s);                %List the coordinates
            
            while strcmp(app.DecawaveSwitch.Value, 'On')
                data = readline(SPort.s);
                timestamp = datestr(now, 'yyyymmddTHHMMSSFFF');
                data_Lns = splitlines(data);                            %Split lines
                numTags = str2double(erase(data_Lns(1), "data: "));     %Extract number of tags
                dataTime = split(data_Lns(3+numTags)," ");              %Extract time info
                t = str2double(erase(dataTime(1), "["));
                r = NaN(3,numTags);
                p = NaN(1,numTags);
                for i = 1:numTags
                    pos_data = split(extractBetween(data_Lns(1+i),"[","]"),",");
                    r(1,i) = str2double(pos_data(1));       %x position
                    r(2,i) = str2double(pos_data(2));       %y position
                    r(3,i) = str2double(pos_data(3));       %z position
                    p(i) = str2double(pos_data(4));         %precision  

                    hold(app.UIAxes,'on');
                    if i==1
                        scatter(app.UIAxes,r(1,i),r(2,i),'filled','b');
                    elseif i==2
                        scatter(app.UIAxes,r(1,i),r(2,i),'filled','r');
                    end
                    xlim(app.UIAxes,[-1 4]); ylim(app.UIAxes,[-1 2]);
                end
                hold(app.UIAxes,'off');
                                    
                SPort.s.UserData.Pos = cat(3,SPort.s.UserData.Pos,r);
                SPort.s.UserData.Time(end+1) = t;
                SPort.s.UserData.Prec(end+1,:) = p;
                
                SPort.s.UserData.Count = SPort.s.UserData.Count + 1;
                disp('Acquiring Decawave Data...');
                fprintf(SPort.DCA,"%s,%s,%s,%s,%s,%s,%s,%s\n",timestamp,dataTime,SPort.s.UserData.Pos(1,1),SPort.s.UserData.Pos(2,1),SPort.s.UserData.Pos(3,1),SPort.s.UserData.Pos(1,2),SPort.s.UserData.Pos(2,2),SPort.s.UserData.Pos(3,2));
            end     
            fclose(SPort.DCA);
            writeline(SPort.s,"gt 14")
        end
        
        function stop_decawave_acquisition(app)
            global SPort
            pause(5);
            writeline(SPort.s,"les")    %Stop quering position
            writeline(SPort.s,"gt 14")   %Toggle Output Pin
            userdata = SPort.s.UserData;
            save(strcat('C:\Users\Batman\Desktop\BatHumanExp\box_3\decawave_backup_',SPort.real_date,'.mat'),'userdata');
        end   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearvars -global SPort
            global SPort
            
            % Initialize the serial port and make it a global object
            SPort.s =  serialport('COM6',115200,'Timeout',3);
            pause(3); % Set longer time because was a delay with only 1s/
            getpinstatus(SPort.s)
            
            writeline(SPort.s,"gc 14");   readline(SPort.s);
            readline(SPort.s);
            flush(SPort.s);
            
            % Add the UserData Field to the serial port object
            SPort.s.UserData = struct("Pos",[],"Prec",[],"Time",[],"Count",1,"Stop",0);
            
             %Check that there are no incoming data
            if ~isempty(readline(SPort.s))
                fprintf('Error: ongoing streaming of serial data \nTry again!! \n');
                flush(SPort.s)
                %SPort.s = serialport('COM6',115200,'Timeout',3);
                pause(3); % Set longer time because was a delay with only 1s/
                writeline(SPort.s,"gc 14");   readline(SPort.s)
                
            else
                disp('Connection OK');
            end
            
            % Get Today's date
            formatout = 2;
            DATE = datestr(now,formatout);
            SPort.real_date = strcat(DATE(end-1:end),DATE(1:2),DATE(4:5));
            
            % Make file to save the decawave data
            SPort.DecawaveFile = fullfile(strcat('C:\\Users\\Batman\\Desktop\\BatHumanExp\\box_3\\decawave_userdata_',SPort.real_date,'.csv'));
        end

        % Value changed function: DecawaveSwitch
        function Switch_SerialComm(app, event)
            SwitchVal = app.DecawaveSwitch.Value;
            global SPort
            if strcmp(SwitchVal, 'On')
                fprintf('Starting Acquisition')
                % Flush the port
                flush(SPort.s);
                % Open file to record positional data
                SPort.DCA = fopen(SPort.DecawaveFile, 'w');
                % Start acquisition
                start_decawave_acquisition(app) 
                
            elseif strcmp(SwitchVal, 'Off')
                fprintf('Stopping Acquisition')
                stop_decawave_acquisition(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Human Position')
            xlabel(app.UIAxes, 'SE')
            ylabel(app.UIAxes, 'SW')
            app.DecawaveSwitch = uiswitch(app.UIFigure, 'rocker');
            app.DecawaveSwitch.ValueChangedFcn = createCallbackFcn(app, @Switch_SerialComm, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Decawave_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end