classdef HLR_humanbatexp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MetadataPanel                   matlab.ui.container.Panel
        Bat1Label                       matlab.ui.control.Label
        BatOne                          matlab.ui.control.EditField
        Bat2Label                       matlab.ui.control.Label
        BatTwo                          matlab.ui.control.EditField
        Bat3EditFieldLabel              matlab.ui.control.Label
        BatThree                        matlab.ui.control.EditField
        Bat4EditFieldLabel              matlab.ui.control.Label
        BatFour                         matlab.ui.control.EditField
        FeedSequenceDropDownLabel       matlab.ui.control.Label
        FeedSequenceDropDown            matlab.ui.control.DropDown
        LearningStageLabel              matlab.ui.control.Label
        LearningStageDropDown           matlab.ui.control.DropDown
        CondSequenceDropDownLabel       matlab.ui.control.Label
        CondSequenceDropDown            matlab.ui.control.DropDown
        BatGroup                        matlab.ui.container.ButtonGroup
        Bat1                            matlab.ui.control.RadioButton
        Bat2                            matlab.ui.control.RadioButton
        Bat3                            matlab.ui.control.RadioButton
        Bat4                            matlab.ui.control.RadioButton
        TaskPanel                       matlab.ui.container.Panel
        TaskSwitch                      matlab.ui.control.RockerSwitch
        TaskDropDown                    matlab.ui.control.DropDown
        Lamp                            matlab.ui.control.Lamp
        Status_label                    matlab.ui.control.Label
        NooutputCheckBox                matlab.ui.control.CheckBox
        TransceiverSyncCheckBox         matlab.ui.control.CheckBox
        TTLBaslerCheckBox               matlab.ui.control.CheckBox
        QUITButton                      matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.TabGroup
        SessionTab                      matlab.ui.container.Tab
        DurationhoursEditFieldLabel     matlab.ui.control.Label
        DurationhoursEditField          matlab.ui.control.NumericEditField
        AutomaticoffafterCheckBox       matlab.ui.control.CheckBox
        EnforcesleephoursCheckBox       matlab.ui.control.CheckBox
        FromEditFieldLabel              matlab.ui.control.Label
        SleepTimeH                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SleepTMin                       matlab.ui.control.NumericEditField
        ForEditFieldLabel               matlab.ui.control.Label
        SleepDur                        matlab.ui.control.NumericEditField
        hrsLabel                        matlab.ui.control.Label
        SounddetectionTab               matlab.ui.container.Tab
        SoundCardLabel                  matlab.ui.control.Label
        ChannelListBoxLabel             matlab.ui.control.Label
        SoundDetecChannel               matlab.ui.control.ListBox
        TriggerthresholdsLabel          matlab.ui.control.Label
        SoundbufferdurationsecSpinnerLabel  matlab.ui.control.Label
        SoundbufferdurationsecSpinner   matlab.ui.control.Spinner
        AmplitudedBEditFieldLabel       matlab.ui.control.Label
        AmplitudedBEditField            matlab.ui.control.NumericEditField
        HighpassfilterkHzLabel          matlab.ui.control.Label
        HighpassfilterkHzEditField      matlab.ui.control.NumericEditField
        RMSEditFieldLabel               matlab.ui.control.Label
        RMSEditField                    matlab.ui.control.NumericEditField
        FiledurationminSpinnerLabel     matlab.ui.control.Label
        FiledurationminSpinner          matlab.ui.control.Spinner
        LowpassfilterkHzEditFieldLabel  matlab.ui.control.Label
        LowpassfilterkHzEditField       matlab.ui.control.NumericEditField
        LowPassCheckBox                 matlab.ui.control.CheckBox
        HighPassCheckBox                matlab.ui.control.CheckBox
        MotuGaindBEditFieldLabel        matlab.ui.control.Label
        MotuGaindBEditField             matlab.ui.control.NumericEditField
        CurrentcallsdetectedEditFieldLabel  matlab.ui.control.Label
        CurrentcallsdetectedEditField   matlab.ui.control.NumericEditField
        DurationmsDropDownLabel         matlab.ui.control.Label
        DurationmsDropDown              matlab.ui.control.DropDown
        PlaybackTab                     matlab.ui.container.Tab
        SoundCardLabel_2                matlab.ui.control.Label
        ChannelListBoxLabel_2           matlab.ui.control.Label
        PlaybackChannel                 matlab.ui.control.ListBox
        SelectListBoxLabel              matlab.ui.control.Label
        PlayListBox                     matlab.ui.control.ListBox
        SoundfilesLabel                 matlab.ui.control.Label
        UpdatefilelistButton            matlab.ui.control.Button
        RepetitionEditFieldLabel        matlab.ui.control.Label
        RepetitionEditField             matlab.ui.control.NumericEditField
        MinIntercallintervalsEditFieldLabel  matlab.ui.control.Label
        MinIntercallintervalsEditField  matlab.ui.control.NumericEditField
        MaxIntercallintervalsEditFieldLabel  matlab.ui.control.Label
        MaxIntercallintervalsEditField  matlab.ui.control.NumericEditField
        CalibratePlayBackGainButton     matlab.ui.control.Button
        CurrentRepEditFieldLabel        matlab.ui.control.Label
        CurrentRepEditField             matlab.ui.control.NumericEditField
        PlayingEditFieldLabel           matlab.ui.control.Label
        PlayingEditField                matlab.ui.control.EditField
        CompensationCheckBox            matlab.ui.control.CheckBox
        PlaybackTaskparametersLabel     matlab.ui.control.Label
        RewardTab                       matlab.ui.container.Tab
        MotorspeedSpinnerLabel          matlab.ui.control.Label
        MotorspeedSpinner               matlab.ui.control.Spinner
        MaxrewardsEditFieldLabel        matlab.ui.control.Label
        MaxrewardsEditField             matlab.ui.control.NumericEditField
        RewarddurationsEditFieldLabel   matlab.ui.control.Label
        RewarddurationsEditField        matlab.ui.control.NumericEditField
        TimedelaytoretrieverewardsEditFieldLabel  matlab.ui.control.Label
        TimedelaytoretrieverewardsEditField  matlab.ui.control.NumericEditField
        CurrentrewardsdeliveredEditFieldLabel  matlab.ui.control.Label
        CurrentrewardsdeliveredEditField  matlab.ui.control.NumericEditField
        LEDCueonDropDownLabel           matlab.ui.control.Label
        LEDCueonDropDown                matlab.ui.control.DropDown
        Delay2retrieverewardStrictFlexDropDown  matlab.ui.control.DropDown
        TimeDelaytoVocalizesfortaskInputBeamVocEditFieldLabel  matlab.ui.control.Label
        TimeDelaytoVocalizesfortaskInputBeamVocEditField  matlab.ui.control.NumericEditField
        RewardPlayBackCheckBox          matlab.ui.control.CheckBox
        PauseafterrewardsLabel          matlab.ui.control.Label
        PauseafterrewardsEditField      matlab.ui.control.NumericEditField
        CurrentBeamBreakdetectedEditFieldLabel  matlab.ui.control.Label
        CurrentBeamBreakdetectedEditField  matlab.ui.control.NumericEditField
        PausebeforerewardsEditFieldLabel  matlab.ui.control.Label
        PausebeforerewardsEditField     matlab.ui.control.NumericEditField
        JitterInterval10EditFieldLabel  matlab.ui.control.Label
        JitterIntervalEditField         matlab.ui.control.NumericEditField
        EatTimeHEditFieldLabel          matlab.ui.control.Label
        EatTimeHEditField               matlab.ui.control.NumericEditField
        EatTimeAEditFieldLabel          matlab.ui.control.Label
        EatTimeAEditField               matlab.ui.control.NumericEditField
        PunishmentTab                   matlab.ui.container.Tab
        DurationsecEditFieldLabel       matlab.ui.control.Label
        TimeOutDuration                 matlab.ui.control.NumericEditField
        TimeoutforignoringthecueCheckBox  matlab.ui.control.CheckBox
        unsuccessfulrewardretrievaltotriggerDuncecapEditFieldLabel  matlab.ui.control.Label
        unsuccessfulrewardretrievaltotriggerDuncecapEditField  matlab.ui.control.NumericEditField
        DuncecapforignoringthecueCheckBox  matlab.ui.control.CheckBox
        DurationsecEditFieldLabel_2     matlab.ui.control.Label
        DunceCapDuration                matlab.ui.control.NumericEditField
        CurrentconsecutiveunsuccessfulrewardretrievalEditFieldLabel  matlab.ui.control.Label
        CurrentconsecutiveunsuccessfulrewardretrievalEditField  matlab.ui.control.NumericEditField
        TotalunsuccessfulrewardretrievalEditFieldLabel  matlab.ui.control.Label
        TotalunsuccessfulrewardretrievalEditField  matlab.ui.control.NumericEditField
        CurrentconsecutiveunsuccessfulrewardretrievalEditField_2Label  matlab.ui.control.Label
        CurrentconsecutiveunsuccessfulrewardretrievalEditField_2  matlab.ui.control.NumericEditField
        ResultsTab                      matlab.ui.container.Tab
        SelectsessionLabel              matlab.ui.control.Label
        SelectDataDropDown              matlab.ui.control.DropDown
        UpdateDataListButton            matlab.ui.control.Button
        UpdategraphButton               matlab.ui.control.Button
        BatOneReLabel                   matlab.ui.control.Label
        BatTwoReLabel                   matlab.ui.control.Label
        BatOneVocLabel                  matlab.ui.control.Label
        BatTwoVocLabel                  matlab.ui.control.Label
        XminEditFieldLabel              matlab.ui.control.Label
        XminEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        SoundtimeDropDownLabel          matlab.ui.control.Label
        SoundtimeDropDown               matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        Results2Tab                     matlab.ui.container.Tab
        ParametersLabel                 matlab.ui.control.Label
        BoxmanualcontrolPanel           matlab.ui.container.Panel
        Reset1Button                    matlab.ui.control.Button
        FwdButton1                      matlab.ui.control.Button
        BwdButton1                      matlab.ui.control.Button
        MotorsLabel                     matlab.ui.control.Label
        LEDsLabel                       matlab.ui.control.Label
        Cue1Button                      matlab.ui.control.Button
        Cue2Button                      matlab.ui.control.Button
        SpeakerLabel                    matlab.ui.control.Label
        MicLabel                        matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        Detect                          matlab.ui.control.StateButton
        MicLamp                         matlab.ui.control.Lamp
        BeamBreakersLabel               matlab.ui.control.Label
        TestFoodBatTwo                  matlab.ui.control.Button
        TestFoodBatOne                  matlab.ui.control.Button
        TestFoodBatThree                matlab.ui.control.Button
        TestFoodBatFour                 matlab.ui.control.Button
        BBLamp                          matlab.ui.control.Lamp
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        Cue3Button                      matlab.ui.control.Button
        Cue4Button                      matlab.ui.control.Button
        Reset2Button                    matlab.ui.control.Button
        FwdButton2                      matlab.ui.control.Button
        BwdButton2                      matlab.ui.control.Button
        Reset3Button                    matlab.ui.control.Button
        FwdButton3                      matlab.ui.control.Button
        BwdButton3                      matlab.ui.control.Button
        Reset4Button                    matlab.ui.control.Button
        FwdButton4                      matlab.ui.control.Button
        BwdButton4                      matlab.ui.control.Button
        TestHand                        matlab.ui.control.Button
        BoxIDLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileTimer % % timer that automatically regularly pauses soundmexpro recording and playing and changes the name of the name of the file recording the input channel
        GraphData % Will contain data of the Results tab Grap
        Timer_AutoOff % Timer that stop the task after the requested duration
    end
    
    methods (Access = private)
        function change_output_rec_wav(app, obj, event, string_arg)
            fprintf('%s at %s\n', string_arg,datestr(now, 'yyyymmddTHHMMSSFFF'))
            global Box
            ChangeFile = tic;
            Box.Task.Output.Sequence = Box.Task.Output.Sequence +1; % this the counter that keeps track of the number of recorded wav files
            MicInd = strfind(Box.Task.Output.Filename_mic, 'mic');
            Filename_temp = Box.Task.Output.Filename_mic(1:MicInd+2);
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            else
                if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.Decawave])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave])-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            end
            
            %Set filename for the microphones inputs
            Box.Task.Output.Filename_mic = sprintf('%s%d_%d.wav', Filename_temp, 1, Box.Task.Output.Sequence);
            Attempt = 0;
            while (1~=soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_mic, 'channel', 'Mic')) && Attempt<20
                Attempt = Attempt+1;
                fprintf(1,'Attempt %d/20 Creating the next Mic output file %s\n',Attempt, Box.Task.Output.Filename_mic)   
            end
            
            % Set filename for the TTL input channel and load the next ttl pulse file on the output track if we're using Soundmexpro generated TTL files
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value
                TTLInd = strfind(Box.Task.Output.Filename_ttl, 'ttl');
                Filename_temp = Box.Task.Output.Filename_ttl(1:TTLInd+3);
                DCAInd = strfind(Box.Task.Output.Filename_dca,'dca');
                Filename_temp_dca = Box.Task.Output.Filename_dca(1:DCAInd+3);
                Box.Task.Output.Filename_ttl = sprintf('%s%d.wav', Filename_temp, Box.Task.Output.Sequence);
                Box.Task.Output.Filename_dca = sprintf('%s%d.wav', Filename_temp_dca, Box.Task.Output.Sequence);
                Attempt = 0;
                while (1~=soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_ttl, 'channel', 'TTL_in')) && Attempt<20
                    Attempt = Attempt+1;
                    fprintf(1,'Attempt %d/20 Creating the next TTL output file %s\n',Attempt,Box.Task.Output.Filename_ttl)
                end
                if app.TransceiverSyncCheckBox.Value
                    if 1~=soundmexpro('loadfile', 'filename', fullfile(Box.Task.TTLWavDir, sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)), 'track', {'TTTL_out_mirror', 'TTTL_out_transceivers'})
                        error('Unable to load ttl wav pulse %s callback function change_output_rec_wav of the timer app.FileTimer', fullfile(Box.Task.TTLWavDir,sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)));
                    end
                end
                while (1~=soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_dca, 'channel', 'Decawave')) && Attempt<20
                    Attempt = Attempt+1;
                    fprintf(1,'Attempt %d/20 Creating the next Decawave output file %s\n',Attempt,Box.Task.Output.Filename_dca)
                end
            end
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            end
            StartTimeStamp = toc(Box.Task.TimerTask);
            StartDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
            if ~app.NooutputCheckBox.Value
                % save the time and sample stamp to file
                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fileevent, StartDateTime,StartTimeStamp, NaN,NaN,NaN, toc(ChangeFile));
            end
            toc(ChangeFile)
        end
        
    end
    
    methods (Access = public)
        
        function Box = init_arduino(app, Box)
%             %% input arduino port connection params given by Windows and the board name
%             if Box.ID == 5
%                 port = 'COM7';
%             elseif (Box.ID == 7) || (Box.ID == 3) || (Box.ID == 4) || (Box.ID == 6) || (Box.ID == 8) || (Box.ID == 2)
%                 port = 'COM3';
%             elseif Box.ID == 1
%                 port = 'COM4';
%             elseif Box.ID == 9
%                 port = 'COM5';
%             end

            % Set the port (COM3 currently, may change when you unplug and
            % replug the arduino)
            port = 'COM3';
            
            % Setting the board name
            board = 'uno';
            
            %% Initializing the Arduino and configure pins
            try
            Box.Arduino = arduino(port, board, 'Libraries', 'Adafruit\MotorShieldV2');
            shield = addon(Box.Arduino, 'Adafruit\MotorShieldV2');
            % Add Second Shield! 
            % addrs = scanI2CBus(Box.Arduino,0)
            % shield2 = addon(Box.Arduino, 'Adafruit\MotorShieldV2','I2CAddress','0x61');
            
            % Setting the pin of LEDs and Beam breakers on the Arduino
            Box.LEDCue1 = 'D10';         % cue for bat #1
            Box.LEDCue2 = 'D11';         % cue for bat #2
            Box.LEDCue3 = 'D12';         % cue for bat #3
            Box.LEDCue4 = 'D13';         % cue for bat #4
            Box.BeamFoodBatOne = 'D2';   % food port beam break input for Bat 1
            Box.BeamFoodBatTwo = 'D4';   % food port beam break input for Bat 2
            Box.BeamFoodBatThree = 'D6'; % food port beam break input for Bat 3
            Box.BeamFoodBatFour = 'D8';  % food port beam break input for Bat 4
            Box.BeamFoodHand = 'D7';     % food port beam break for hand feeder
            
            configurePin(Box.Arduino, Box.BeamFoodBatOne, 'pullup');
            configurePin(Box.Arduino, Box.BeamFoodBatTwo, 'pullup');
            configurePin(Box.Arduino, Box.BeamFoodBatThree, 'pullup');
            configurePin(Box.Arduino, Box.BeamFoodBatFour, 'pullup');
            configurePin(Box.Arduino, Box.BeamFoodHand, 'pullup');
            configurePin(Box.Arduino, Box.LEDCue1, 'DigitalOutput');
            configurePin(Box.Arduino, Box.LEDCue2, 'DigitalOutput');
            configurePin(Box.Arduino, Box.LEDCue3, 'DigitalOutput');
            configurePin(Box.Arduino, Box.LEDCue4, 'DigitalOutput');
            
            % Make sure LEDs are switched off
            writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); %start LEDpin at low
            writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); %start LEDpin at low
            writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); %start LEDpin at low
            writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); %start LEDpin at low
            
            %% Initializing the motors
            Box.dcm1 = dcmotor(shield, 1); % Defining the syringe motor #1
            Box.dcm2 = dcmotor(shield, 2); % Defining the syringe motor #2
            Box.dcm3 = dcmotor(shield, 3); % Defining the syringe motor #3
            Box.dcm4 = dcmotor(shield, 4); % Defining the syringe motor #4
            Box.dcm1.Speed = app.MotorspeedSpinner.Value; % Get the speed of the syringe motor
            Box.dcm2.Speed = app.MotorspeedSpinner.Value; % Get the speed of the syringe motor
            Box.dcm3.Speed = app.MotorspeedSpinner.Value; % Get the speed of the syringe motor
            Box.dcm4.Speed = app.MotorspeedSpinner.Value; % Get the speed of the syringe motor
            catch
            end
        end
        
        function [Box] = init_soundmex(app, Box)
            %% Initialize recording and playback channels, audio saving paths, AmpTracks and audio buffer filename
            % When channels are set up as vectors, soundmexPro will pick which ever
            % from those has signal.
            % To see possible configuration on the MOTU before initialization, type:
            % soundmexpro_showdevs
            % Channels available on MOTU 896mk3:
            % soundmex output channels 0 and 1 correspond to Main Out on sound card
            % soundmex output channels 2 and 3 correspond to Analog out 1 and 2 on sound card
            % soundmex output channels 4 and 5 correspond to Analog out 3 and 4 on sound card
            % soundmex output channels 6 and 7 correspond to Analog out 5 and 6 on sound card
            % soundmex output channels 8 and 9 correspond to Analog out 7 and 8 on sound card
            % soundmex input channels 0 and 1 correspond to Analog in 1 and 2 on sound card
            % soundmex input channels 2 and 3 correspond to Analog in 3 and 4 on sound card
            % soundmex input channels 4 and 5 correspond to Analog in 5 and 6 on sound card
            % soundmex input channels 6 and 7 correspond to Analog in 7 and 8 on sound card
            
%             if (Box.ID == 3) || (Box.ID == 4) || (Box.ID == 2) || (Box.ID == 1)
%                 Box.Channels.Mic = 0; % or (0:1)
%                 Box.Channels.Play = 2;
%                 Box.Channels.PhonesL = 0;
%                 Box.Channels.PhonesR = 1;
%                 Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
%                 Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
%                 Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output
%             elseif (Box.ID == 6) || (Box.ID == 8) || (Box.ID == 5) || (Box.ID == 7)
%                 Box.Channels.Mic = 1; % or (0:1)
%                 Box.Channels.Play = 2;
%                 Box.Channels.PhonesL = 0;
%                 Box.Channels.PhonesR = 1;
%                 Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
%                 Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
%                 Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output
%             elseif Box.ID == 9
%                 Box.Channels.Mic = 0; % or (0:1)
%                 Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
%                 Box.Channels.Play = 2;
%                 Box.Channels.PhonesL = 0;
%                 Box.Channels.PhonesR = 1;
%                 Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
%                 Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output on soundcard
%             end
            
            % Configure Channels on the Box (from Box 9 code)
            Box.Channels.Mic = 0; % or (0:1)
            Box.Channels.Decawave = 2; %(Channel 3)
            Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
            Box.Channels.Play = 2;
            Box.Channels.PhonesL = 0;
            Box.Channels.PhonesR = 1;
            Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
            Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output on soundcard
            
            Curr_path = pwd;
            filepartitions=strfind(Curr_path, '\');
            %Box.RecPath=sprintf('%s\\Desktop\\bataudio\\autoTrain\\box_%d\\', Curr_path(1:(filepartitions(3)-1)), Box.ID);
            %Box.PlayPath=sprintf('%s\\Desktop\\bataudio\\autoTrain\\playback\\', Curr_path(1:(filepartitions(3)-1)));
            fmat=2;
            DATE = datestr(now,fmat);
            realdate=strcat(DATE(7:8),DATE(1:2),DATE(4:5));
            Box.RecPath=sprintf('C:\\Users\\Batman\\Desktop\\BatHumanExp\\box_%d\\%s\\session%d',Box.ID,realdate,Box.Session);
            Box.PlayPath=sprintf('C:\\Users\\Batman\\Desktop\\BatHumanExp\\playback\\');
            if ~exist(Box.RecPath)
                mkdir(Box.RecPath);
            else
                disp(strcat("Overwriting Session ",num2str(Box.Session)," data!!"));
            end
            
            Box.MicFile=fullfile(Box.RecPath, sprintf('mic%d_box%d.wav', Box.Channels.Mic, Box.ID));
            
            Box.TTLFile = fullfile(Box.RecPath, sprintf('ttl_box%d.wav', Box.ID));
            
            % Add decawave file
            Box.DecawaveFile = fullfile(Box.RecPath, sprintf('decawave_box%d.wav', Box.ID));
            
            % Set names of the channels for the sound card in the app dropdown menus
            clear soundmexpro % In case it was already created by some previous runs
            ID='MOTU Audio ASIO';
            [~, Outch,Inch]=soundmexpro('getchannels', 'driver', ID);
            app.SoundDetecChannel.Items = Inch;
            app.PlaybackChannel.Items = Outch;
            app.SoundDetecChannel.Value = app.SoundDetecChannel.Items(Box.Channels.Mic+1);
            app.PlaybackChannel.Value = app.PlaybackChannel.Items(Box.Channels.Play+1);
            
            % Set the gain for playing files
            GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain*'));
            if isempty(GainFile)
                fprintf(1,'Warning!!! No Gain file for this set-up the default value (1) will be used for playing sound files with soundmexpro\n')
                Box.PlayBackGain = 1;
            elseif app.CompensationCheckBox.Value
                GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain_Compensated.mat'));
                if isempty(GainFile)
                    fprintf(1,'Warning!!! No Gain file compensated for this set up, the non compensated one is used\n');
                    GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain.mat'));
                end
                Gain = load(fullfile(GainFile.folder, GainFile.name));
                Box.PlayBackGain = Gain.Gain_in;
            else
                GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain.mat'));
                if isempty(GainFile)
                    fprintf(1,'Warning!!! No Gain file NOT compensated for this set up, the compensated one is used\n');
                    GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain_Compensated.mat'));
                end
                Gain = load(fullfile(GainFile.folder, GainFile.name));
                Box.PlayBackGain = Gain.Gain_in;
            end
            
            %% set default sample frequency
            Box.SoundCard.fs=192000;
            
            %% initialize soundmexpro
            nbufs=2; %Set the # of buffers for recording
            
            %% Include the Decawave channel
            soundmexpro('init','driver',ID,'samplerate',Box.SoundCard.fs,'input',[Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in],...
                'output',[Box.Channels.PhonesL Box.Channels.PhonesR Box.Channels.Play Box.Channels.TTL_out_mirror Box.Channels.TTL_out_transceivers],...
                'track',length([Box.Channels.PhonesL Box.Channels.PhonesR Box.Channels.Play Box.Channels.TTL_out_mirror Box.Channels.TTL_out_transceivers]),...
                'autocleardata',1,'numbufs',nbufs);
            soundmexpro('show')
            
            %% map tracks to output channels
            % new mapping, here the indices in the vector refer to the new indices of output channels in soundmexpro irrespective of the indices of the soundcard output channels
            % Setting one track per output channel
            if 1~= soundmexpro('trackmap', ...
                    'track', 0:(length([Box.Channels.PhonesL Box.Channels.PhonesR Box.Channels.Play Box.Channels.TTL_out_mirror Box.Channels.TTL_out_transceivers])-1))
                error('error setting trackmap in init_soundmex %s\n', datestr(now,30));
            end
            
            % Name output channels
            if 1~= soundmexpro('channelname', 'output', [0 1 2 3 4], 'name', {'PhoneL', 'PhoneR', 'Speaker', 'TTL_out_mirror', 'TTL_out_transceivers'})
                error('could not set up output channel names in init_soundmex\n')
            end
                        
            % Include Decawave input channel %MCS
            if 1~= soundmexpro('recname', 'input', [0 1 2], 'name', {'Mic', 'Decawave','TTL_in'})
               error('Could not set up input channel names in init_soundmex\n')
            end
            
            % Name tracks
            if 1~= soundmexpro('trackname', 'track', [0 1 2 3 4], 'name', {'TPhoneL', 'TPhoneR','TSpeaker', 'TTTL_out_mirror', 'TTTL_out_transceivers'})
                error('Could not set up input channel names in init_soundmex\n')
            end
            
            % Map the input Mic to Phones
            if 1~= soundmexpro('iostatus', 'input', {'Mic','Mic'}, 'track', {'TPhoneL', 'TPhoneR'})
                error('Could not map input Mic channel to Phones in init_soundmex\n')
            end
            
            % Type the following command to see the track mapping in soundmexpro:
            %soundmexpro_showcfg
            
            % Include Decawave input channel
            if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                error('error setting recpause in init_soundmex %s\n', datestr(now,30));
            end
            
            %% Disabling writing to the recording file for all input channels
            if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                error('error setting recpause in init_soundmex %s\n', datestr(now,30));
            end
            
            %% Set default filename for the Mic input channel
            soundmexpro('recfilename', 'filename', Box.MicFile, 'channel', {'Mic'});
            
            %% Set default filename for the TTL input channel
            soundmexpro('recfilename', 'filename', Box.TTLFile, 'channel', {'TTL_in'}); % The TTL channel is always the last one irrespective of the number of Mic Channels
            
            %% Set default filename for the Decawave inptu channel
            soundmexpro('recfilename', 'filename', Box.DecawaveFile, 'channel', {'Decawave'});
            
            %% List the files in the playback folder
            Files = dir(fullfile(Box.PlayPath, '*.wav'));
            app.PlayListBox.ItemsData = cell(length(Files),1);
            for ff=1:length(Files)
                app.PlayListBox.Items{ff} = Files(ff).name;
                app.PlayListBox.ItemsData{ff} = fullfile(Box.PlayPath, Files(ff).name);
            end
            
            %% set the values from which we can choose the clipping duration for sound detection
            [~,~,Bufsiz]=soundmexpro('getproperties');
            DurThreshValues=round((1:round(app.SoundbufferdurationsecSpinner.Value*Box.SoundCard.fs/Bufsiz))*Bufsiz*1000/Box.SoundCard.fs); % make proper # of buffers (threshdur & fs dependent)
            app.DurationmsDropDown.Items = mat2cell(num2str(DurThreshValues'),ones(length(DurThreshValues),1),length(num2str(DurThreshValues(end))));
            app.DurationmsDropDown.ItemsData = DurThreshValues;
            
            % Initialize place to put bat
            Box.HLRBat = 0;
        end
        
        
        function [Success, Stamp, Wave] = soundDetect(app,Box,NumBufClip, Filter, RmsThresh)
            Success=0;
            Stamp=[];
            Wave=[];
            %% Querry the number of cliping buffers from last reset in the input channels
            [Errorcode, ~ , Clipin, ~]=soundmexpro('clipcount');
            if ~Errorcode % Some hang of the driver most likely happening need to reach out to someone!!
                sendmail(Box.Emails, sprintf('Hang detected Box %d at %s',Box.ID, datestr(now, 'yyyymmddTHHMMSSFFF')));
            end
            %% CHECK the sound extract that hit the clipping duration threshold on the main first recording channel
            if Clipin(1)>=NumBufClip
                callOnlyTrigger = datestr(now, 'yyyymmddTHHMMSSFFF');
                [~,rectrg,Stamp_local]=soundmexpro('recgetdata','channel','Mic'); % get sound data
                
                %% Clipping threshlods in number of samples
                ClipSamp = app.DurationmsDropDown.Value/1000 * Box.SoundCard.fs;
                
                if app.LowPassCheckBox.Value || app.HighPassCheckBox.Value
                    filt_trg=filtfilt(Filter.B,Filter.A,rectrg(end-ClipSamp:end,1)); % apply the frequency filter only on the sound segment that hit the minimum # of clipping buffers
                else
                    filt_trg = rectrg(end-ClipSamp:end,1);
                end
                H=rms(filt_trg); % calculate RMS of the filtered sound extract
                if H<RmsThresh % Not reaching RMS threshold, the sound extract is discarded. may need to increase if still get cage noise
                    %                     fprintf('rms of frequency filtered sound trigger NOT reaching threshold %i >>>>> %s\nTriggered at %s\n', H, datestr(now,'HH:MM:SS'),callOnlyTrigger);
                else
                    Success=1;
                    if isfield(Box, 'Task')
                        Stamp= sprintf('%d_%d', Box.Task.Output.Sequence,Stamp_local+length(rectrg)-ClipSamp-1);
                        % Update counter of vocalizations
                        app.CurrentcallsdetectedEditField.Value = app.CurrentcallsdetectedEditField.Value + 1;
                        fprintf(1,'Sound reaching thresholds! >>> RMS=%.4f.\n', H);
                    else
                        fprintf(1,'Sound reaching thresholds! >>> RMS=%.4f. Registered at %s. Triggered at %s\n', H, datestr(now,'yyyymmddTHHMMSSFFF'),callOnlyTrigger);
                    end
                    Wave = rectrg(end-ClipSamp:end,1); % sound snippet returned by the function that reached all detection requirements
                end
                
                if 1~=soundmexpro('resetclipcount') % reset all clip counters to zero
                    warning('Failed to reset clipcount in SoundDetect at %s\n',datestr(now, 'yyyymmddTHHMMSSFFF'));
                    if app.Lamp.Color(2) % We are running a task
                        % log in the parameter log the error onset time
                        fprintf(Box.Task.Output.Fid_out_param, 'ResetClipCount Error starts at: %s; Time after task onset: %.5f\n', datestr(now, 'yyyymmddTHHMMSSFFF'),toc(Box.Task.TimerTask));
                        % create a timer to give a few seconds to the sounds card to respond
                        Soundcard_timer = tic;
                        ResetOk = 0;
                        while ~ResetOk || toc(Soundcard_timer)<2
                            ResetOk=soundmexpro('resetclipcount');
                        end
                        if ResetOk
                            % log in the parameter log the error offset time and proceed
                            fprintf(Box.Task.Output.Fid_out_param, 'ResetClipCount Error resolved at: %s; Time after task onset: %.5f\n', datestr(now, 'yyyymmddTHHMMSSFFF'),toc(Box.Task.TimerTask));
                        else
                            % log in the parameter log the error trigger time and bug
                            fprintf(Box.Task.Output.Fid_out_param, 'ResetClipCount Error unresolved at: %s; Time after task onset: %.5f\n', datestr(now, 'yyyymmddTHHMMSSFFF'),toc(Box.Task.TimerTask));
                            error('Failed to reset clipcount in SoundDetect')
                        end
                    else % we are just testing the function
                        error('Failed to reset clipcount in SoundDetect')
                    end
                end
            end
        end
        
        function [NumBufClip,Filter,RmsThresh] = set_sound_detection_parameters(app, Box)
            % Get threshold in terms of amplitude
            AmpThresh = app.AmplitudedBEditField.Value;%-35;
            % set threshold in terms of clipping threshold on soundmex
            if 1 ~= soundmexpro('clipthreshold','type','input','value',...
                    10^(AmpThresh/20))
                error('error setting clipthreshold');
            end
            
            % get duration threshold from app NumBufClip IS THE NUMBER OF BUFFERS
            % EXPECTED TO BE CLIPPING FOR A SOUND REACHING AMPLITUDE TRHESHOLD
            % FOR THE DURATION DURTHRESH
            DurThresh=app.DurationmsDropDown.Value/1000;% 5ms;
            % get asio properties
            [~,~,Bufsiz]=soundmexpro('getproperties');
            NumBufClip=round(DurThresh*Box.SoundCard.fs/Bufsiz); % make proper # of buffers (threshdur & fs dependent)
            
            % Set the frequency filter(s) that is (are) applied on the sound extract candidate
            if app.HighPassCheckBox.Value && ~app.LowPassCheckBox.Value % Only high pass filter requested
                % get the frequency threshold from the app
                FreqThresh=app.HighpassfilterkHzEditField.Value;
                % design a 4th order high pass Butterworth filter with cutoff
                % frequency at freqThresh
                [Filter.B,Filter.A]=butter(4,2*FreqThresh*1000/Box.SoundCard.fs,'high');
            elseif ~app.HighPassCheckBox.Value && app.LowPassCheckBox.Value % Only low pass filter requested
                % get the frequency threshold from the app
                FreqThresh=app.LowpassfilterkHzEditField.Value;
                % design a 4th order high pass Butterworth filter with cutoff
                % frequency at freqThresh
                [Filter.B,Filter.A]=butter(4,2*FreqThresh*1000/Box.SoundCard.fs,'low');
            elseif app.HighPassCheckBox.Value && app.LowPassCheckBox.Value % band pass filter requested
                % get the frequency threshold from the app
                FreqThresh=[app.HighpassfilterkHzEditField.Value app.LowpassfilterkHzEditField.Value];
                % design a 4th order high pass Butterworth filter with cutoff
                % frequency at freqThresh
                [Filter.B,Filter.A]=butter(2,2*FreqThresh*1000/Box.SoundCard.fs, 'bandpass');
            else
                Filter = [];
            end
            
            if app.LowPassCheckBox.Value
                
            else
                Filter.Low.A=[];
                Filter.Low.B=[];
            end
            
            %Set the RMS threshold that the filtered sound extract candidate
            %should reach to be kept as a vocalization trigger.
            RmsThresh=app.RMSEditField.Value;
            
            %Set the duration of the sound sample that should be recorded upon detection (value taken from app)
            % set duration of the record buffer for the function recgetdata
            % Note that is buffer is different from the internal buffer sound
            % card. This RecBuf is the length of the sound vector that should
            % be retrieve by soundmexpro when the function recgetdata is called
            % get the desired duration from the app
            if app.SoundbufferdurationsecSpinner.Value<=app.DurationmsDropDown.Value/1000
                error('Duration of sound sample that should be recorded upon detection\nis smaller than the duration of the sound extract on which\nthe threshold for detection was calculated:\nParameters-> Sound detection: sound extract duration <= Trigger threshold duration\nIt needs to be larger!!!\n');
            end
            %         if app.SoundbufferdurationsecSpinner<=recShift
            %             error('Duration of sound sample that should be recorded upon detection\nis smaller than the shift used to postpone sound recording after sound detection threshold is hit:\n(recDur<=recShift)\nIt needs to be larger!!!\n');
            %         end
            if 1 ~= soundmexpro( 'recbufsize', 'value', app.SoundbufferdurationsecSpinner.Value*Box.SoundCard.fs)
                error(['error calling ''recbufsize''' error_loc(dbstack)]);
            end
        end
        
        function [] = findgainsoundmex(app, Box,In,Fs, Mic, SP, Gain_in, Compensation)
            
            % Rectify the signal to find Input max value
            MaxIn = max(abs(In));
            if Compensation
                % set the frequency limit, sample frequency for the butterworth filter and calculate the filer
                Fl=0.5e3;
                Fh=90e3;
                [B,A]=butter(8,2.*[Fl./Fs Fh./Fs]);
                % compensate for the Microphone
                Out=filter(Mic.irc,1,In);
                % compensate for the speaker
                Out = filter(SP.irc,1,Out);
                % Apply butter worth filter
                Out=filter(B,A,Out);
                % Scale the signal between -0.8 and 0.8
                Out=Out./max(abs(Out)).*0.8;
                % Apply a sin wav gating of 10ms to the sound
                wds=0.01; % gate duration in s
                npts=length(Out);
                f=1/(2*wds);
                t=0:1/Fs:2*wds-1/Fs;
                m=(1+sin(2*pi*t*f-pi/2))/2;
                mpts=length(m);
                if mpts>npts
                    error('window too long for this stimulus!');
                end
                Out(1:floor(mpts/2)) = Out(1:floor(mpts/2)) .* m(1:floor(mpts/2))';
                Out(npts-ceil(mpts/2):npts)=Out(npts-ceil(mpts/2):npts).*m(mpts-ceil(mpts/2):mpts)';
            else
                Out=In;
            end
            
            % Start recording from soundmexpro
            if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                error('Soundmexpro cannot resume recording using recpause\n');
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % Play sound
            soundmexpro('loadmem', 'data', Out, 'track', 'TSpeaker', 'loopcount',1, 'gain',Gain_in);
            pause(length(Out)/Fs)
            
            % Stop recording and playing
            if 1 ~= soundmexpro('stop')
                error('error stop recording and playing');
            end
            % Stop writing to the recording files for all input channels
            if 1~=soundmexpro('recpause', 'value', ones(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                error('error setting recpause\n');
            end
            
            % load the recorded file
            [In2,~] = audioread(Box.MicFile);
            % Rectify the signal to find Input max value
            MaxIn2 = max(abs(In2));
            % Check if sound played at the same volume
            if round(MaxIn2*1000) == round(MaxIn*1000)
                fprintf(1,'Gain adjusted to %f! MaxIn = %d and MaxIn2 = %d\n', Gain_in, round(MaxIn*1000)/1000, round(MaxIn2*1000)/1000);
                if Compensation
                    File_out = fullfile(Box.RecPath,'SoundMexPro_Gain_Compensated.mat');
                else
                    File_out = fullfile(Box.RecPath,'SoundMexPro_Gain.mat');
                end
                fprintf(1,'Saving value of gain for this box under %s\n',File_out)
                save(File_out, 'Gain_in');
            elseif MaxIn2>MaxIn
                Gain_new = Gain_in - Gain_in*0.001;
                fprintf(1,'MaxIn = %.4f and MaxIn2 = %.4f\nGain to high adjusted to %f! \n', MaxIn, MaxIn2,Gain_new);
                findgainsoundmex(app, Box, In,Fs, Mic, SP, Gain_new, Compensation);
            elseif MaxIn2<MaxIn
                Gain_new = Gain_in + Gain_in*0.001;
                fprintf(1,'MaxIn = %.4f and MaxIn2 = %.4f\nGain to low adjusted to %f! \n', MaxIn, MaxIn2,Gain_new);
                findgainsoundmex(app, Box, In,Fs, Mic, SP, Gain_new, Compensation);
            end
        end
        
    end
    
    methods (Access = private)
        
        function results = disable_sound_detection_inputs(app)
            app.SoundDetecChannel.Enable = 'off';
            app.SoundbufferdurationsecSpinner.Enable = 'off';
            app.DurationmsDropDown.Enable = 'off';
            app.AmplitudedBEditField.Enable = 'off';
            app.HighpassfilterkHzEditField.Enable = 'off';
            app.LowpassfilterkHzEditField.Enable = 'off';
            app.HighPassCheckBox.Enable = 'off';
            app.LowPassCheckBox.Enable = 'off';
            app.RMSEditField.Enable = 'off';
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Enable = 'off';
            results=1;
        end
        
        function results = enable_sound_detection_inputs(app)
            app.SoundDetecChannel.Enable = 'on';
            app.SoundbufferdurationsecSpinner.Enable = 'on';
            app.DurationmsDropDown.Enable = 'on';
            app.AmplitudedBEditField.Enable = 'on';
            app.HighpassfilterkHzEditField.Enable = 'on';
            app.LowpassfilterkHzEditField.Enable = 'on';
            app.HighPassCheckBox.Enable = 'on';
            app.LowPassCheckBox.Enable = 'on';
            app.RMSEditField.Enable = 'on';
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Enable = 'on';
            results=1;
        end
                
        function results = disable_metadata_inputs(app)
            app.BatOne.Enable = 'off';
            app.BatTwo.Enable = 'off';
            app.BatThree.Enable = 'off';
            app.BatFour.Enable = 'off';
            results=1;
        end
        
        function results = enable_metadata_inputs(app)
            app.BatOne.Enable = 'on';
            app.BatTwo.Enable = 'on';
            app.BatThree.Enable = 'on';
            app.BatFour.Enable = 'on';
            results=1;
        end
        
        function results = disable_session_parameters_inputs(app)
            app.DurationhoursEditField.Enable = 'off';
            app.AutomaticoffafterCheckBox.Enable = 'off';
            app.EnforcesleephoursCheckBox.Enable = 'off';
            app.SleepTimeH.Enable = 'off';
            app.SleepTMin.Enable = 'off';
            app.SleepDur.Enable = 'off';
            results = 1;
        end
        
        function results = enable_session_parameters_inputs(app)
            app.DurationhoursEditField.Enable = 'on';
            app.AutomaticoffafterCheckBox.Enable = 'on';
            app.EnforcesleephoursCheckBox.Enable = 'on';
            app.SleepTimeH.Enable = 'on';
            app.SleepTMin.Enable = 'on';
            app.SleepDur.Enable = 'on';
            results = 1;
        end
        
        function results = disable_playback_parameters_inputs(app)
            app.PlaybackChannel.Enable = 'off';
            app.PlayListBox.Enable = 'off';
            app.UpdatefilelistButton.Enable = 'off';
            app.RepetitionEditField.Enable = 'off';
            app.MinIntercallintervalsEditField.Enable = 'off';
            app.MaxIntercallintervalsEditField.Enable = 'off';
            app.CompensationCheckBox.Enable = 'off';
            app.CalibratePlayBackGainButton.Enable = 'off';
            results = 1;
        end
        
        function results = enable_playback_parameters_inputs(app)
            app.PlaybackChannel.Enable = 'on';
            app.PlayListBox.Enable = 'on';
            app.UpdatefilelistButton.Enable = 'on';
            app.RepetitionEditField.Enable = 'on';
            app.MinIntercallintervalsEditField.Enable = 'on';
            app.MaxIntercallintervalsEditField.Enable = 'on';
            app.CompensationCheckBox.Enable = 'on';
            app.CalibratePlayBackGainButton.Enable = 'on';
            results = 1;
        end
        
        function results = disable_reward_parameters_input(app)
            app.MotorspeedSpinner.Enable = 'off';
            app.MaxrewardsEditField.Enable = 'off';
            app.RewarddurationsEditField.Enable = 'off';
            app.TimedelaytoretrieverewardsEditField.Enable = 'off';
            app.Delay2retrieverewardStrictFlexDropDown.Enable = 'off';
            app.LEDCueonDropDown.Enable = 'off';
            results = 1;
        end
        
        function results = enable_reward_parameters_input(app)
            app.MotorspeedSpinner.Enable = 'on';
            app.MaxrewardsEditField.Enable = 'on';
            app.RewarddurationsEditField.Enable = 'on';
            app.TimedelaytoretrieverewardsEditField.Enable = 'on';
            app.Delay2retrieverewardStrictFlexDropDown.Enable = 'on';
            app.LEDCueonDropDown.Enable = 'on';
            results = 1;
        end
        
        function results = disable_punishment_parameters_input(app)
            app.TimeoutforignoringthecueCheckBox.Enable = 'off';
            app.TimeOutDuration.Enable = 'off';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Enable = 'off';
            app.DuncecapforignoringthecueCheckBox.Enable = 'off';
            app.DunceCapDuration.Enable = 'off';
            results=1;
        end
        
        function results = enable_punishment_parameters_input(app)
            app.TimeoutforignoringthecueCheckBox.Enable = 'on';
            app.TimeOutDuration.Enable = 'on';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Enable = 'on';
            app.DuncecapforignoringthecueCheckBox.Enable = 'on';
            app.DunceCapDuration.Enable = 'on';
            results=1;
        end
        
        function results = disable_manual_control_buttons(app)
            app.Reset1Button.Enable = 'off';
            app.Reset2Button.Enable = 'off';
            app.Reset3Button.Enable = 'off';
            app.Reset4Button.Enable = 'off';
%             app.FwdButton1.Enable = 'off';
%             app.BwdButton1.Enable = 'off';
%             app.FwdButton2.Enable = 'off';
%             app.BwdButton2.Enable = 'off';
%             app.FwdButton3.Enable = 'off';
%             app.BwdButton3.Enable = 'off';
%             app.FwdButton4.Enable = 'off';
%             app.BwdButton4.Enable = 'off';
%             app.FwdButton5.Enable = 'off';
%             app.BwdButton5.Enable = 'off';
            app.Cue1Button.Enable = 'off';
            app.Cue2Button.Enable = 'off';
            app.Cue3Button.Enable = 'off';
            app.Cue4Button.Enable = 'off';
            %app.PlayButton.Enable = 'off';
            app.Detect.Enable = 'off';
            results=1;
        end
        
        function results = enable_manual_control_buttons(app)
            app.Reset1Button.Enable = 'on';
            app.FwdButton1.Enable = 'on';
            app.BwdButton1.Enable = 'on';
            app.Reset2Button.Enable = 'on';
            app.FwdButton2.Enable = 'on';
            app.BwdButton2.Enable = 'on';
            app.Reset3Button.Enable = 'on';
            app.FwdButton3.Enable = 'on';
            app.BwdButton3.Enable = 'on';
            app.Reset4Button.Enable = 'on';
            app.FwdButton4.Enable = 'on';
            app.BwdButton4.Enable = 'on';
            app.Cue1Button.Enable = 'on';
            app.Cue2Button.Enable = 'on';
            app.Cue3Button.Enable = 'on';
            app.Cue4Button.Enable = 'on';
            app.PlayButton.Enable = 'on';
            app.Detect.Enable = 'on';
            results=1;
            
        end
        
        function init_task(app, TaskType)
            global Box
            % Set up the date
            Today = datestr(now, 'yymmdd_HHMM');
            
            % Create variable for which bat is selected
            if app.Bat1.Value == 1
                Box.HLRBat = app.BatOne.Value;
            elseif app.Bat2.Value == 1
                Box.HLRBat = app.BatTwo.Value;
            elseif app.Bat3.Value == 1
                Box.HLRBat = app.BatThree.Value;
            elseif app.Bat4.Value == 1
                Box.HLRBat = app.BatFour.Value;
            end
            
            % Set up output wavefile name, parameter file for the task and result file for events' time stamp
            if strcmp(TaskType,'FeedTask')
                Filename_param = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_param.txt', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType));
                Box.Task.Output.Fid_out_param = fopen(Filename_param, 'wt');   
            elseif strcmp(TaskType,'HLRTask')
                Filename_param = fullfile(Box.RecPath, sprintf('%s_%s_%s_param.txt', Box.HLRBat, Today, TaskType));
                Box.Task.Output.Fid_out_param = fopen(Filename_param, 'wt');  
            elseif strcmp(TaskType,'RecOnly')
                Filename_param = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_param.txt', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType));
                Box.Task.Output.Fid_out_param = fopen(Filename_param, 'wt');   
            end
            if Box.Task.Output.Fid_out_param==-1
                error('Could not create output file %s\n', Filename_param);
            end
            
            if strcmp(TaskType,'FeedTask')
                Filename_events = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_events.txt', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType));
                Box.Task.Output.Fid_out_events = fopen(Filename_events, 'wt');
            elseif strcmp(TaskType,'HLRTask')
                Filename_events = fullfile(Box.RecPath, sprintf('%s_%s_%s_events.txt', Box.HLRBat, Today, TaskType));
                Box.Task.Output.Fid_out_events = fopen(Filename_events, 'wt');   
            elseif strcmp(TaskType, 'RecOnly')
                Filename_events = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_events.txt', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType));
                Box.Task.Output.Fid_out_events = fopen(Filename_events, 'wt');
            end
            if Box.Task.Output.Fid_out_events==-1
                error('Could not create output file %s\n', Filename_events);
            end
                        
            if strcmp(TaskType,'FeedTask')
                fprintf(Box.Task.Output.Fid_out_events, 'DateTime\tTimeStamp(s)\tType\tBat1\tBat2\tBat3\tBat4\n');
                Box.Task.Output.FormatSpec_vocevent = '%s\t%.5f\tVocalization\t%f\t%f\t%f\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_fpevent_auto = '%s\t%.5f\tAUTO\t%f\t%f\t%f\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_fpevent_hand = '%s\t%.5f\tHAND\t%f\t%f\t%f\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\tChangeFile\t%f\t%f\t%f\t%f\n'; %MCS%
                %Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\t%s\tChangeFile\t%d\t%d\t%f\n'; %MCS%
            elseif strcmp(TaskType,'HLRTask')
                fprintf(Box.Task.Output.Fid_out_events, 'DateTime\tTimeStamp(s)\tType\tDirection\tStage\tTime2Reward\n');
                Box.Task.Output.FormatSpec_R1AUTO = '%s\t%.5f\tAUTO\tR\tStage1\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_L1AUTO = '%s\t%.5f\tAUTO\tL\tStage1\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_R2AUTO = '%s\t%.5f\tAUTO\tR\tStage2\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_L2AUTO = '%s\t%.5f\tAUTO\tL\tStage2\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_R3AUTO = '%s\t%.5f\tAUTO\tR\tStage3\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_L3AUTO = '%s\t%.5f\tAUTO\tL\tStage3\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_R3HAND = '%s\t%.5f\tHAND\tR\tStage3\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_L3HAND = '%s\t%.5f\tHAND\tL\tStage3\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\tChangeFile\t%f\t%f\t%f\t%f\n'; %MCS%
            elseif strcmp(TaskType,'RecOnly')
                fprintf(Box.Task.Output.Fid_out_events, 'DateTime\tTimeStamp(s)\tType\tBat1\tBat2\tBat3\tBat4\n');
                Box.Task.Output.FormatSpec_vocevent = '%s\t%.5f\tVocalization\t%f\t%f\t%f\t%f\n'; %MCS%
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\tChangeFile\t%f\t%f\t%f\t%f\n'; %MCS%
            end

            % Reset counters for rewards, punishments, trials
             Box.Task.BatFailuresRight = 0;
             Box.Task.BatFailuresLeft = 0;
             Box.Task.BatFailures = [0, 0, 0; 0, 0, 0];
             Box.Task.BatFailuresFeeding = [0, 0, 0, 0; 0, 0, 0, 0];
             
             % Initialize variables so that I can start and stop the
             % experiment by hand.
             Box.Task.HandFeedInitialize = 0;
             Box.Task.HandFeedTerminate = 0;
%             app.CurrentconsecutiveunsuccessfulrewardretrievalBatOneEditField.Value = 0;
%             app.CurrentconsecutiveunsuccessfulrewardretrievalBatTwoEditField.Value = 0;
%             app.CurrentconsecutiveunsuccessfulrewardretrievalBatThreeEditField.Value = 0;
%             app.CurrentconsecutiveunsuccessfulrewardretrievalBatFourEditField.Value = 0;
%             app.TotalunsuccessfulrewardretrievalBatOneEditField.Value = 0;
%             app.TotalunsuccessfulrewardretrievalBatTwoEditField.Value = 0;
%             app.TotalunsuccessfulrewardretrievalBatThreeEditField.Value = 0;
%             app.TotalunsuccessfulrewardretrievalBatFourEditField.Value = 0;
%             app.CurrentrewardsdeliveredBatOneEditField.Value = 0;
%             app.CurrentrewardsdeliveredBatTwoEditField.Value = 0;
%             app.CurrentrewardsdeliveredBatThreeEditField.Value = 0;
%             app.CurrentrewardsdeliveredBatFourEditField.Value = 0;
%             app.CurrentBeamBreakdetectedBatOneEditField.Value = 0;
%             app.CurrentBeamBreakdetectedBatTwoEditField.Value = 0;
%             app.CurrentBeamBreakdetectedBatThreeEditField.Value = 0;
%             app.CurrentBeamBreakdetectedBatFourEditField.Value = 0;
%             app.CurrentcallsdetectedBatOneEditField.Value = 0;
%             app.CurrentcallsdetectedBatTwoEditField.Value = 0;
%             app.CurrentcallsdetectedBatThreeEditField.Value = 0;
%             app.CurrentcallsdetectedBatFourEditField.Value = 0;
            
            % Set filename for the recording input channel
            Box.Task.Output.Sequence = 1; % this the counter that keeps track of the number of recorded wav files
            if strcmp(TaskType,'FeedTask')
                Box.Task.Output.Filename_mic = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_mic%d_%d.wav', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType, 1, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_mic, 'channel', 'Mic');
            elseif strcmp(TaskType,'HLRTask')
                Box.Task.Output.Filename_mic = fullfile(Box.RecPath, sprintf('%s_%s_%s_mic%d_%d.wav', Box.HLRBat, Today, TaskType, 1, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_mic, 'channel', 'Mic');
            elseif strcmp(TaskType,'RecOnly')
                Box.Task.Output.Filename_mic = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_mic%d_%d.wav', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType, 1, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_mic, 'channel', 'Mic');
            end
            
            % Set filename for the TTL input channel
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value
                if strcmp(TaskType,'FeedTask')
                    Box.Task.Output.Filename_ttl = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_ttl_%d.wav', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType, Box.Task.Output.Sequence));
                    soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_ttl, 'channel', 'TTL_in');
                elseif strcmp(TaskType,'HLRTask')
                    Box.Task.Output.Filename_ttl = fullfile(Box.RecPath, sprintf('%s_%s_%s_ttl_%d.wav', Box.HLRBat, Today, TaskType, Box.Task.Output.Sequence));
                    soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_ttl, 'channel', 'TTL_in');
                elseif strcmp(TaskType,'RecOnly')
                    Box.Task.Output.Filename_ttl = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_ttl_%d.wav', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType, Box.Task.Output.Sequence));
                    soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_ttl, 'channel', 'TTL_in');
                end
            end
            
            % Set filename for Decawave channel
            if strcmp(TaskType,'FeedTask')
                Box.Task.Output.Filename_dca = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_dca_%d.wav', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_dca, 'channel', 'Decawave');
            elseif strcmp(TaskType,'HLRTask')
                Box.Task.Output.Filename_dca = fullfile(Box.RecPath, sprintf('%s_%s_%s_dca_%d.wav', Box.HLRBat, Today, TaskType, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_dca, 'channel', 'Decawave');
            elseif strcmp(TaskType,'RecOnly')
                Box.Task.Output.Filename_dca = fullfile(Box.RecPath, sprintf('%s%s%s%s_%s_%s_dca_%d.wav', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_dca, 'channel', 'Decawave');
            end
           
            % Create a timer that automatically regularly pauses soundmexpro recording and playing and changes the names of the file recording the input channel
            app.FileTimer = timer;
            app.FileTimer.Name = 'file-timer';
            app.FileTimer.ExecutionMode = 'fixedRate';
            app.FileTimer.Period = 60*app.FiledurationminSpinner.Value;
            app.FileTimer.StartDelay = 60*app.FiledurationminSpinner.Value;
            app.FileTimer.TimerFcn = {@app.change_output_rec_wav, 'File switch\n'};
                            
            % Create folder for the output wavfiles (sound snippets that reached tresholds and that are saved by matlab)
            Box.Task.Output.SnipDir = fullfile(Box.RecPath,sprintf('%s%s%s%s_%s_%s_snippets', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today,TaskType));
            if ~(exist(Box.Task.Output.SnipDir, 'dir')==7)
                mkdir(Box.Task.Output.SnipDir);
            end
            Box.Task.Output.SnipFile = sprintf('%s%s%s%s_%s_%s_snipfile', app.BatOne.Value(1:2), app.BatTwo.Value(1:2), app.BatThree.Value(1:2), app.BatFour.Value(1:2), Today, TaskType);
            
            % Get ready TTL playback files if we're using soundmex TTL generated pulses
            if app.TransceiverSyncCheckBox.Value
                Box.Task.TTLWavDir = fullfile(Box.RecPath,'TTL_Wav');
                if ~(exist(Box.Task.TTLWavDir, 'dir')==7)
                    mkdir(Box.Task.TTLWavDir);
                end
                % Determine how long the task is, default is set to how long task is by default +1 hours
                if app.AutomaticoffafterCheckBox.Value
                    TaskDuration = app.DurationhoursEditField.Value;
                else
                    TaskDuration = app.DurationhoursEditField.Value + 1;
                end
                % default value for TTL pulses file is the same as the duration of output recording files
                
                % find out if the last TTl wav that were created had the same parameters and are still here
                CreateTTL = 1;
                %Get the directory contents
                Dirc = dir(fullfile(Box.Task.TTLWavDir, '*unique_ttl_params.mat'));
                %I contains the index to the biggest number which is the latest file
                [~,I] = max([Dirc(:).datenum]);
                if ~isempty(I)
                    Lastparam = load(fullfile(Box.Task.TTLWavDir,Dirc(I).name));
                    if (Lastparam.TotalDuration>=TaskDuration) && (Lastparam.FileDuration==app.FiledurationminSpinner.Value) && (Lastparam.FS == Box.SoundCard.fs) && (strcmp(Lastparam.TTLCode, 'Value'))
                        % Same parameters as today. Let's check that files are still there
                        TotalDuration_samp = Lastparam.TotalDuration*3600*Lastparam.FS;
                        FileDuration_samp = Lastparam.FileDuration*60*Lastparam.FS; % # samples on an individual playback files
                        N_files = ceil(TotalDuration_samp/FileDuration_samp);
                        LastTTL = dir(fullfile(Box.Task.TTLWavDir, 'unique_ttl*.wav'));
                        if length(LastTTL)==N_files
                            Today = datestr(now, 'yymmdd_HHMM');
                            save(fullfile(Box.Task.TTLWavDir, [Today 'unique_ttl_params.mat']),'-struct', 'Lastparam');
                            CreateTTL=0;
                        end
                    end
                end
                if CreateTTL
                    Current_dir = pwd;
                    Code_Ind = strfind(Current_dir, 'Code');
                    Path2TTLfolder = [Current_dir(1:(Code_Ind + 4)) '/neurobat-hardware-alignment'];
                    addpath(genpath(Path2TTLfolder));
                    generate_zero_playback_files('TotalDuration', TaskDuration,'FileDuration',app.FiledurationminSpinner.Value,'FS',Box.SoundCard.fs, 'TTLCode', 'Value', 'Path', Box.Task.TTLWavDir)
                end
                if 1~=soundmexpro('loadfile', 'filename', fullfile(Box.Task.TTLWavDir, sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)), 'track', {'TTTL_out_mirror', 'TTTL_out_transceivers'})
                    error('Unable to load ttl wav pulse %s in init_task',fullfile(Box.Task.TTLWavDir, sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)));
                end
            end
            
            % Save parameter values to file:
            if strcmp(TaskType,'FeedTask')
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #1: %s.\n', app.BatOne.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #2: %s.\n', app.BatTwo.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #3: %s.\n', app.BatThree.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #4: %s.\n', app.BatFour.Value);
                if app.AutomaticoffafterCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic off requested after: %.2f hours\n', app.DurationhoursEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic off not requested\n');
                end
                if app.EnforcesleephoursCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours requested\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours not requested\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Input Channel: %s\n', cell2mat(app.SoundDetecChannel.Value));
                fprintf(Box.Task.Output.Fid_out_param, 'Motu gain: %d dB\n', app.MotuGaindBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Mic recording saved to: %s\n', Box.Task.Output.Filename_mic);
                fprintf(Box.Task.Output.Fid_out_param, 'Events saved to: %s\n', Filename_events);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection amplitude threshold: %.2f dB\n', app.AmplitudedBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection duration threshold: %.2f ms\n', app.DurationmsDropDown.Value);
                if app.HighPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: %.2f Hz\n', app.HighpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: None\n');
                end
                if app.LowPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: %.2f Hz\n', app.LowpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: None\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection RMS threshold: %.3f\n', app.RMSEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Time delay to get reward: %f s\n', app.TimedelaytoretrieverewardsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Delay to get reward: %s\n', app.Delay2retrieverewardStrictFlexDropDown.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Cue  LED mode: %s\n', app.LEDCueonDropDown.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Reward duration: %f s\n', app.RewarddurationsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Motor speed: %f\n', app.MotorspeedSpinner.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Rewarding playback: %f\n', app.RewardPlayBackCheckBox.Value);
                if app.RewardPlayBackCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Playback file: %s\n',app.PlayListBox.Value{1});
                end
                if app.TimeoutforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue requested of: %f s\n', app.TimeOutDuration.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue not requested\n');
                end
                if app.DuncecapforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue requested of: %f s after %d unsuccessful trials\n', app.DunceCapDuration.Value, app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue not requested\n');
                end
                if app.TransceiverSyncCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal requested\n');
                elseif app.TTLBaslerCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with external TTL signal requested\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal not requested\n');
                end 
                start(app.FileTimer);
            elseif strcmp(TaskType,'HLRTask')
                fprintf(Box.Task.Output.Fid_out_param, 'Bat: %s.\n', Box.HLRBat);
                fprintf(Box.Task.Output.Fid_out_param, '%s.\n', app.LearningStageDropDown.Value);
                if app.AutomaticoffafterCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic off requested after: %.2f hours\n', app.DurationhoursEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic off not requested\n');
                end
                if app.EnforcesleephoursCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours requested\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours not requested\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Input Channel: %s\n', cell2mat(app.SoundDetecChannel.Value));
                fprintf(Box.Task.Output.Fid_out_param, 'Motu gain: %d dB\n', app.MotuGaindBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Mic recording saved to: %s\n', Box.Task.Output.Filename_mic);
                fprintf(Box.Task.Output.Fid_out_param, 'Events saved to: %s\n', Filename_events);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection amplitude threshold: %.2f dB\n', app.AmplitudedBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection duration threshold: %.2f ms\n', app.DurationmsDropDown.Value);
                if app.HighPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: %.2f Hz\n', app.HighpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: None\n');
                end
                if app.LowPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: %.2f Hz\n', app.LowpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: None\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection RMS threshold: %.3f\n', app.RMSEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Time delay to get reward: %f s\n', app.TimedelaytoretrieverewardsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Delay to get reward: %s\n', app.Delay2retrieverewardStrictFlexDropDown.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Cue  LED mode: %s\n', app.LEDCueonDropDown.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Reward duration: %f s\n', app.RewarddurationsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Motor speed: %f\n', app.MotorspeedSpinner.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Rewarding playback: %f\n', app.RewardPlayBackCheckBox.Value);
                if app.RewardPlayBackCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Playback file: %s\n',app.PlayListBox.Value{1});
                end
                if app.TimeoutforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue requested of: %f s\n', app.TimeOutDuration.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue not requested\n');
                end
                if app.DuncecapforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue requested of: %f s after %d unsuccessful trials\n', app.DunceCapDuration.Value, app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue not requested\n');
                end
                if app.TransceiverSyncCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal requested\n');
                elseif app.TTLBaslerCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with external TTL signal requested\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal not requested\n');
                end 
                start(app.FileTimer);
            elseif strcmp(TaskType,'RecOnly')
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #1: %s.\n', app.BatOne.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #2: %s.\n', app.BatTwo.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #3: %s.\n', app.BatThree.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Bat #4: %s.\n', app.BatFour.Value);
                if app.AutomaticoffafterCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic off requested after: %.2f hours\n', app.DurationhoursEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic off not requested\n');
                end
                if app.EnforcesleephoursCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours requested\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours not requested\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Input Channel: %s\n', cell2mat(app.SoundDetecChannel.Value));
                fprintf(Box.Task.Output.Fid_out_param, 'Motu gain: %d dB\n', app.MotuGaindBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Mic recording saved to: %s\n', Box.Task.Output.Filename_mic);
                fprintf(Box.Task.Output.Fid_out_param, 'Events saved to: %s\n', Filename_events);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection amplitude threshold: %.2f dB\n', app.AmplitudedBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection duration threshold: %.2f ms\n', app.DurationmsDropDown.Value);
                if app.HighPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: %.2f Hz\n', app.HighpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: None\n');
                end
                if app.LowPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: %.2f Hz\n', app.LowpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: None\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection RMS threshold: %.3f\n', app.RMSEditField.Value);
                if app.RewardPlayBackCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Playback file: %s\n',app.PlayListBox.Value{1});
                end
                if app.TimeoutforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue requested of: %f s\n', app.TimeOutDuration.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue not requested\n');
                end
                if app.DuncecapforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue requested of: %f s after %d unsuccessful trials\n', app.DunceCapDuration.Value, app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue not requested\n');
                end
                if app.TransceiverSyncCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal requested\n');
                elseif app.TTLBaslerCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with external TTL signal requested\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal not requested\n');
                end 
                start(app.FileTimer);
            end
        end
        
        function stop_task(app, TaskType, obj, event)
            global Box
            % End the task
            % Stop and delete from memory the timer that automatically changes output files
            stop(app.FileTimer);
            delete(app.FileTimer);
            
            % By default a recOnly session is not automatically started unless it is requested
            RecOnlyStart = 0;
            
            % Decide if the stop function is reached after the maximum number of reward was reached
%             if app.MaxrewardsEditField.Value == app.CurrentrewardsdeliveredEditField.Value
%                 % reset the motor
%                 reset1_button_pushed(app)
%                 % start a reconly task
%                 RecOnlyStart = 1;
%             end
            
            % Stop and delete from memory the timer that automatically stop the task after a fix duration if it was requested
            % Check if a RecOnly session should be started
             if app.AutomaticoffafterCheckBox.Value
                 if strcmp(app.TaskSwitch.Value, 'Start') && (app.MaxrewardsEditField.Value < app.CurrentrewardsdeliveredEditField.Value) % stop_task has been reached automatically with the timer
                     disp(' ********* Reaching automatic stop time! ***********');
                     % reset the motor
                     %reset1_button_pushed(app)
                     % start a reconly task
                     %RecOnlyStart = 1; %Setting to 1 the automatic start of a RecOnly session after the present one
                 end
                 stop(app.Timer_AutoOff);
                 delete(app.Timer_AutoOff);
             end
            
%             Stop recording and playing
            if 1 ~= soundmexpro('stop')
                error('error stop recording and playing in stop_task %s\n', datestr(now,30));
            end

            % Stop writing to the recording files for all input channels
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                    error('error setting recpause in stop_task %s\n', datestr(now,30));
                end
            else
                if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.Decawave])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave])-1))
                    error('error setting recpause in stop_task %s\n', datestr(now,30));
                end
            end
            
            app.Status_label.Text = 'Closing';
            % Close output files if nooutput unchecked
            % if no output uncheked
            if ~app.NooutputCheckBox.Value
                if strcmp(app.TaskDropDown.Value, 'FeedTask')
                    fprintf(Box.Task.Output.Fid_out_param, 'Task stops at %s after %d seconds\n', datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask));
                    fprintf(Box.Task.Output.Fid_out_param, 'Total number of sequences or wav files of %d min: %d\n', app.FiledurationminSpinner.Value, Box.Task.Output.Sequence);
                    %fprintf(Box.Task.Output.Fid_out_param, 'Total number of vocalizations detected (all bats): %d\n', app.CurrentcallsdetectedEditField.Value);
                    fprintf(Box.Task.Output.Fid_out_param, 'Feeding Sequence: %s\n',app.FeedSequenceDropDown.Value);
                    fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful AUTO/HAND trials for Bat #1: %d / %d\n',Box.Task.BatFailuresFeeding(1,1),Box.Task.BatFailuresFeeding(2,1));
                    fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful AUTO/HAND trials for Bat #2: %d / %d\n',Box.Task.BatFailuresFeeding(1,2),Box.Task.BatFailuresFeeding(2,2));
                    fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful AUTO/HAND trials for Bat #3: %d / %d\n',Box.Task.BatFailuresFeeding(1,3),Box.Task.BatFailuresFeeding(2,3));
                    fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful AUTO/HAND trials for Bat #4: %d / %d\n',Box.Task.BatFailuresFeeding(1,4),Box.Task.BatFailuresFeeding(2,4));
                    fclose(Box.Task.Output.Fid_out_param);
                    fclose(Box.Task.Output.Fid_out_events);
                elseif strcmp(app.TaskDropDown.Value,'HLRTask')
                    fprintf(Box.Task.Output.Fid_out_param, 'Task stops at %s after %d seconds\n', datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask));
                    fprintf(Box.Task.Output.Fid_out_param, 'Total number of sequences or wav files of %d min: %d\n', app.FiledurationminSpinner.Value, Box.Task.Output.Sequence);
                    %fprintf(Box.Task.Output.Fid_out_param, 'Total number of vocalizations detected (all bats): %d\n', app.CurrentcallsdetectedEditField.Value);
                    if strcmp(app.LearningStageDropDown.Value,'Stage 1')
                        fprintf(Box.Task.Output.Fid_out_param, 'Only Auto-Feeds in %s\n',app.LearningStageDropDown.Value);
                        fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful AUTO/HAND trials: %d\n',Box.Task.BatFailuresRight+Box.Task.BatFailuresLeft);
                    elseif strcmp(app.LearningStageDropDown.Value,'Stage 2')
                        fprintf(Box.Task.Output.Fid_out_param, 'Only Auto-Feeds in %s\n',app.LearningStageDropDown.Value);
                        fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful AUTO/HAND trials: %d\n',Box.Task.BatFailuresRight+Box.Task.BatFailuresLeft);
                    elseif strcmp(app.LearningStageDropDown.Value,'Stage 3')
                        fprintf(Box.Task.Output.Fid_out_param, 'Sequence of AUTO and HAND feeds in %s\n',app.LearningStageDropDown.Value);
                        fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful Condition 1 (AUTO, NO HUMAN) trials %d\n',Box.Task.BatFailures(1,1)+Box.Task.BatFailures(2,1));
                        fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful Condition 2 (AUTO, HUMAN) trials %d\n',Box.Task.BatFailures(1,2)+Box.Task.BatFailures(2,2));
                        fprintf(Box.Task.Output.Fid_out_param, 'Total number of unsuccessful Condition 3 (HAND) trials %d\n',Box.Task.BatFailures(1,3)+Box.Task.BatFailures(2,3));
                    end
                    fclose(Box.Task.Output.Fid_out_param);
                    fclose(Box.Task.Output.Fid_out_events);
                elseif strcmp(app.TaskDropDown.Value,'RecOnly')
                    fclose(Box.Task.Output.Fid_out_param);
                    fclose(Box.Task.Output.Fid_out_events);
                end
            end
            
            fprintf(1,'******* SESSION SUMMARY ***********\n');
            %fprintf(1, 'Total number of beam breaks detected: %d\n', app.CurrentBeamBreakdetectedEditField.Value);
            %fprintf(1, 'Total number of vocalizations detected: %d\n', app.CurrentcallsdetectedEditField.Value);
            %fprintf(1, 'Total number of reward delivered: %d\n',app.CurrentrewardsdeliveredEditField.Value);
            %fprintf(1, 'Total number of unsuccessful trials: %d\n',app.TotalunsuccessfulrewardretrievalEditField.Value);
%             
            % Set filename in soundmexpro to default value (close the previously written file)
            soundmexpro('recfilename', 'filename', Box.MicFile, 'channel', {'Mic'});
            % Include decawave
            soundmexpro('recfilename', 'filename', Box.DecawaveFile, 'channel', {'Decawave'});
            %if app.TransceiverSyncCheckBox.Value
            soundmexpro('recfilename', 'filename', Box.TTLFile, 'channel', {'TTL_in'});
            %end
          
  
            % Change color of status LED on app to off and light on Time out LED in the box
            %app.Lamp.Color = [0 0 0];
            ArduinoOffline =1;
            Arduino_timer = tic;
            while ArduinoOffline && (toc(Arduino_timer)<(5*60))
                try
                    writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); % Make sure LED #1 off
                    writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % Make sure LED #2 off
                    writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); % Make sure LED #3 off
                    writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % Make sure LED #4 off
                    ArduinoOffline = 0;
                catch ME
                    fprintf(1,'Arduino not responding, keep trying to reach it... every 5sec for %.1f min \n', round(10-toc(Arduino_timer)/6)/10);
                    pause(5)
                end
            end
            if ArduinoOffline
                rethrow(ME)
            end
            app.TaskSwitch.Value = 'Stop';
            app.Status_label.Text = 'Status';
            
            % Enable all inputs
            enable_manual_control_buttons(app);
            enable_metadata_inputs(app);
            enable_session_parameters_inputs(app);
            enable_sound_detection_inputs(app);
            enable_playback_parameters_inputs(app);
            enable_reward_parameters_input(app);
            enable_punishment_parameters_input(app);
            
            % Start RecOnly session if requested
            if RecOnlyStart
                % start a reconly task
                % CHange the task type
                app.TaskDropDown.Value = 'RecOnly';
                % Set the timer of the task to 10hrs
                app.DurationhoursEditField.Value = 10;
                % hit the start button
                app.TaskSwitch.Value = 'Start';
                task_switch_action(app);
            end
        end
        
        function rec_only_task(app)
            global Box
            % No reward, just passive recording saved to file, with sound detection active and saving tigger times to file
            fprintf('****************************\n')
            fprintf('* running Record Only task *\n')
            fprintf('****************************\n')
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;

            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if a sound is detected
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            while strcmp(app.TaskSwitch.Value, 'Start')
                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                if Success && ~app.NooutputCheckBox.Value
                    % if a sound is detected and nooutput unchecked save the time and sample stamp to file
                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask),NaN,NaN,NaN,NaN);
                    % also save the sound snippet on which sound was detected '%s\t%.5f\tVocalization\t%f\t%f\t%f\t%f\n'
                    audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                end
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                % the following check make sure we're not goign into a re-entrancy error when the timer_AutoOff is running its call back function
                if app.AutomaticoffafterCheckBox.Value && ~isvalid(app.Timer_AutoOff)
                    break
                end
            end
        end 
        

        function feeding_phase_task(app)
            global Box % Global variable defining the Box object 
            fprintf('***********************************\n')
            fprintf('* running feeding phase task #1 *\n')
            fprintf('***********************************\n')
            if app.RewardPlayBackCheckBox.Value
                % Pulling filename of call to play before reward
                Filename = app.PlayListBox.Value{1};
                % Check the sample frequency of the file, it should be the same as the Motu
                [~, fs] = audioread(Filename);
                if fs~=Box.SoundCard.fs
                    error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                end
            end
            
            % Initialize the Initializer
            Box.Task.HandFeedInitialize = 0;
            Box.Task.HandFeedTerminate = 0;
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0]; % Define the LED color 
            app.Status_label.Text = 'Recording'; % Change the status label to "recording"
            if ~app.NooutputCheckBox.Value % If the No Output box isn't checked, print task start info 
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic; % Start the task timer
            
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value % If using Tranciever or avisoft TTL add additional channel to record
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end 
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask); % Timestamp of the last check 
            FoodPort_old = 0; % Foodport old value
            
            % Below, && means that the right operand only calculated if
            % left operand is true. So if sum(FoodPort) = 1, then look at
            % ~sum(FoodPort_old). This means, if there is a beam break,
            % detect it
            
            % Define sequence of auto v.s. hand feeds.
            if app.FeedSequenceDropDown.Value == 'M'
                disp("Auto Hand Auto Hand")
                Box.Feed_Sequence = [0 0 0 0 ...
                                     1 1 1 1 ...
                                     0 0 0 0 ...
                                     1 1 1 1 ... 
                                     0 0 0 0 ...
                                     1 1 1 1 ...
                                     0 0 0 0 ...
                                     1 1 1 1 ...
                                     0 0 0 0];
            elseif app.FeedSequenceDropDown.Value == 'A'
                disp("Auto Feeds Only");
                Box.Feed_Sequence = [0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0 ...
                                     0 0 0 0];                     
            elseif app.FeedSequenceDropDown.Value == 'A12'
                disp("Auto Feeds Only");
                Box.Feed_Sequence = [0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1 ...
                                     0 0 -1 -1];
            elseif app.FeedSequenceDropDown.Value == 'A34'
                disp("Auto Feeds Only");
                Box.Feed_Sequence = [-1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0 ...
                                     -1 -1 0 0];
            elseif app.FeedSequenceDropDown.Value == 'H12'
                disp("Hand Feeds Only");
                Box.Feed_Sequence = [1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1 ...
                                     1 1 -1 -1];
            elseif app.FeedSequenceDropDown.Value == 'H34'
                disp("Hand Feeds Only");
                Box.Feed_Sequence = [-1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1 ...
                                     -1 -1 1 1];
            elseif app.FeedSequenceDropDown.Value == 'TA'
                disp("testing, only two rounds of feeds")
                Box.Feed_Sequence = [0 0 0 0 ...
                                     0 0 0 0];
            elseif app.FeedSequenceDropDown.Value == 'TH'
                disp("testing, only two rounds of feeds")
                Box.Feed_Sequence = [1 1 1 1 ...
                                     1 1 1 1];
            end
            WaitTime = app.TimedelaytoretrieverewardsEditField.Value;
            disp("Waiting for Experimenter to Begin")
            while Box.Task.HandFeedInitialize == 0
                Box.Task.HandFeedInitialize = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand);
            end
            while strcmp(app.TaskSwitch.Value, 'Start') && (Box.Task.HandFeedInitialize == 1)
                disp("Beginning the experiment in 3 seconds");
                pause(3);
                FeedingPhaseTimer = tic;
                for i=1:length(Box.Feed_Sequence) % For 40 trials... (each bat gets 10)
                    if strcmp(app.TaskSwitch.Value, 'Stop')
                        break;
                    end
                    cb = mod(i,4); % Get current bat compartment 
                    disp(strcat("On Bat ",num2str(cb)));
                    if cb == 1 && Box.Feed_Sequence(i) == 0
                        writeDigitalPin(Box.Arduino, Box.LEDCue1, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic; 
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatOne); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #1 to eat from AUTO food port\n")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under the Wait Time...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatOne); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                deliver_reward_B1(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,BT,NaN,NaN,NaN);
                                disp("Bat #1 got reward from AUTO!");
                                pause(app.EatTimeAEditField.Value);
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,BT,NaN,NaN,NaN);
                                disp("Bat #1 did not get reward from AUTO");
                                Box.Task.BatFailuresFeeding(1,cb) = Box.Task.BatFailuresFeeding(1,cb) + 1;
                                break;
                            end
                        end
                    elseif cb == 1 && Box.Feed_Sequence(i) == 1
                        writeDigitalPin(Box.Arduino, Box.LEDCue1, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                        disp(FoodPort)
                        disp("Waiting for Bat #1 to eat from HAND food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under 2 min...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand) % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #1 got reward from HAND!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #1 did not get reward from HAND.");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,BT,NaN,NaN,NaN);
                            Box.Task.BatFailuresFeeding(2,cb) = Box.Task.BatFailuresFeeding(2,cb) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B2(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,BT,NaN,NaN,NaN);
                            pause(app.EatTimeHEditField.Value);
                        end
                    elseif cb == 2 && Box.Feed_Sequence(i) == 0
                        writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #2 to eat from AUTO food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #2 got reward from AUTO!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #2 did not get reward from AUTO");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,NaN,BT,NaN,NaN);
                            Box.Task.BatFailuresFeeding(1,cb) = Box.Task.BatFailuresFeeding(1,cb) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B2(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,NaN,BT,NaN,NaN);
                            pause(app.EatTimeAEditField.Value);
                        end
                    elseif cb == 2 && Box.Feed_Sequence(i) == 1
                        writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #2 to eat from HAND food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under 2 min...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #2 got reward from HAND!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #2 did not get reward from HAND.");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,NaN,BT,NaN,NaN);
                            Box.Task.BatFailuresFeeding(2,cb) = Box.Task.BatFailuresFeeding(2,cb) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B2(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,NaN,BT,NaN,NaN);
                            pause(app.EatTimeHEditField.Value);
                        end
                    elseif cb == 3 && Box.Feed_Sequence(i) == 0
                        writeDigitalPin(Box.Arduino, Box.LEDCue3, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatThree); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #3 to eat from AUTO food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under 2 min...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatThree); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #3 got reward from AUTO!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #3 did not get reward from AUTO");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,NaN,NaN,BT,NaN);
                            Box.Task.BatFailuresFeeding(1,cb) = Box.Task.BatFailuresFeeding(1,cb) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B3(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,NaN,NaN,BT,NaN);
                            pause(app.EatTimeAEditField.Value);
                        end
                    elseif cb == 3 && Box.Feed_Sequence(i) == 1
                        writeDigitalPin(Box.Arduino, Box.LEDCue3, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #3 to eat from HAND food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under 2 min...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #3 got reward from HAND!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #3 did not get reward from HAND.");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,NaN,NaN,BT,NaN);
                            Box.Task.BatFailuresFeeding(2,cb) = Box.Task.BatFailuresFeeding(2,cb) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B4(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,NaN,NaN,BT,NaN);
                            pause(app.EatTimeHEditField.Value);
                        end
                    elseif cb == 0 && Box.Feed_Sequence(i) == 0
                        writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #4 to eat from AUTO food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under 2 min...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #4 got reward from AUTO!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #4 did not get reward from AUTO");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,NaN,NaN,NaN,BT);
                            Box.Task.BatFailuresFeeding(1,4) = Box.Task.BatFailuresFeeding(1,4) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B4(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_auto,TriggerDateTime, TriggerTimeStamp,NaN,NaN,NaN,BT);
                            pause(app.EatTimeAEditField.Value);
                        end
                    elseif cb == 0 && Box.Feed_Sequence(i) == 1
                        writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                        BatTimer = tic;
                        FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                        disp("Waiting for Bat #4 to eat from HAND food port")
                        while (FoodPort+FoodPort_old) == 0 || toc(BatTimer) < WaitTime %While the foodport is NOT broken and we are under 2 min...
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                            if (FoodPort+FoodPort_old) == 1
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #4 got reward from HAND!");
                                break;
                            elseif toc(BatTimer) > WaitTime
                                TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                BT = toc(BatTimer);
                                disp("Bat #4 did not get reward from HAND.");
                                break;
                            end
                        end
                        if BT > WaitTime
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,NaN,NaN,NaN,BT);
                            Box.Task.BatFailuresFeeding(2,4) = Box.Task.BatFailuresFeeding(2,4) + 1;
                        elseif BT < WaitTime
                            deliver_reward_B4(app);
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent_hand,TriggerDateTime, TriggerTimeStamp,NaN,NaN,NaN,BT);
                            pause(app.EatTimeHEditField.Value);
                        end
                    elseif Box.Feed_Sequence(i) == -1
                        rng(0,'twister');
                        b = 10;
                        a = 30;
                        jitter=(b-a).*rand(1)+a;
                        pause(jitter);
                    end
                                      
                    NewCheck = toc(Box.Task.TimerTask); 
                    Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                    Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                    LastCheck = NewCheck;
                    pause(1) % this pause forces the code to check the status of buttons and any pending callback function
                    % the following check make sure we're not goign into a re-entrancy error when the timer_AutoOff is running its call back function
                    if app.AutomaticoffafterCheckBox.Value && ~isvalid(app.Timer_AutoOff)
                        break
                    end  
                end
                DurationOfFeedingPhase = toc(FeedingPhaseTimer);
                disp(strcat("Total Duration: ", num2str(DurationOfFeedingPhase)));
                disp(strcat("Total Number of Feeds: ",num2str(length(Box.Feed_Sequence))," = ",num2str(length(Box.Feed_Sequence)/4)," per bat."));
                break;
            end
            fprintf('Feeding Task is Over.\n')
            fprintf('Waiting for Experimentor to End Recording')
            while Box.Task.HandFeedTerminate == 0
                Box.Task.HandFeedTerminate = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand);
            end
            stop_task(app);
        end
        
        function HLR_task(app)

            % This task is coded in 3 stages of learning. Stage 1 is until
            % they start to approach the feeder. Stage 2 is until they get
            % 70% of presented opportunities to feed. Stage 3 is the actual
            % experiment. 
            % (1.1) light goes on on either the right side (R) or left side (L)
            % (1.2) a TINY about of smoothie is pushed from the syringe (smell cue)
            % (1.3) bat is given 2 minutes to go to the feeder and activate the feeder to get the smoothie
            % (1.4) after 2 minutes the bat is picked up and transported back to its 'home segment'
            % (1.5) if the bat activated the feeder within the 2 min it is recorded as a success, if not it is recorded as a failure. 
            
            % (2.1) light goes on on either the right side (R) or left side (L)
            % (2.2) bat is given 1 minute to go to the feeder and activate the feeder to get the smoothie
            % (2.3) after 1 minute the bat is picked up and transported back to its 'home segment'.
            % (2.4) if the bat activated the feeder within the 1 min it is recorded as a success, if not it is recorded as a failure. 
            
            % (3.1) light goes on on either the right side (R) or left side (L)
            % (3.2) bat is given 1 minute to go to the feeder and activate the feeder (or hand feeder) to get the smoothie.
            % (3.3) after 1 minute the bat is picked up and transported back to its 'home segment'. 
            % (3.4) if the bat activated the feeder (or hand-feeder) within the 1 min it is recorded as a success UNDER THE CONDITION THAT WAS PRESENTED, 
            % if not it is recorded as a failure UNDER THE CONDITION THAT WAS PRESENTED.
            
            global Box % Global variable defining the Box object 
            fprintf('***********************************\n')
            fprintf('* running HLR task *\n')
            fprintf('***********************************\n')
            if app.RewardPlayBackCheckBox.Value
                % Pulling filename of call to play before reward
                Filename = app.PlayListBox.Value{1};
                % Check the sample frequency of the file, it should be the same as the Motu
                [~, fs] = audioread(Filename);
                if fs~=Box.SoundCard.fs
                    error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                end
            end
            
            % Initialize the Initializer and Terminator
            Box.Task.HandFeedInitialize = 0;
            Box.Task.HandFeedTerminate = 0;
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0]; % Define the LED color 
            app.Status_label.Text = 'Recording'; % Change the status label to "recording"
            if ~app.NooutputCheckBox.Value % If the No Output box isn't checked, print task start info 
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic; % Start the task timer
            
            if app.TransceiverSyncCheckBox.Value || app.TTLBaslerCheckBox.Value % If using Tranciever or avisoft TTL add additional channel to record
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.Decawave])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.Decawave])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask); % Timestamp of the last check 
            FoodPort_old = 0; % Foodport old value
            
            % Below, && means that the right operand only calculated if
            % left operand is true. So if sum(FoodPort) = 1, then look at
            % ~sum(FoodPort_old). This means, if there is a beam break,
            % detect it
            
            % Define sequence of conditions (90 potential trials; 30 of each condition)
            % Key:
            %   "R1" = Condition 1 on right side
            %   "L3" = Condition 3 on the left side 
          
            % Start task on experimentor's command. 
            WaitTime = app.TimedelaytoretrieverewardsEditField.Value;
            disp("Waiting for Experimenter to Begin")
            while Box.Task.HandFeedInitialize == 0
                Box.Task.HandFeedInitialize = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand);
            end
            while strcmp(app.TaskSwitch.Value, 'Start') && (Box.Task.HandFeedInitialize == 1)
                disp("Beginning the experiment in 3 seconds");
                pause(3);
                % Do Stage 1 Learning
                if app.LearningStageDropDown.Value == 'Stage 1'
                    disp("DEPRECATED. Beginning Stage 1. R-L-R-L repeat with small reward at beginning.")
                    % Keep track of number of failures. 
                    Box.Task.BatFailuresRight = 0; Box.Task.BatFailuresLeft = 0;
                    % Make a random value for each trial for the jitter
                    % beween trials
                    rng(0,'twister');
                    b = app.JitterIntervalEditField.Value+10;
                    a = app.JitterIntervalEditField.Value-10;
                    rightfeederidx = [1,2,3,7,8,9];
                    leftfeederidx = [4,5,6,10,11,12];
                    for i=1:15
                        if strcmp(app.TaskSwitch.Value, 'Stop')
                            break;
                        end
                        % Random jitter
                        jitter=(b-a).*rand(1)+a;
                        % To the right 
                        %if i < 22
                        %if mod(i,2)==1 
                        if ismember(i,leftfeederidx)
                            % Turn on the LED
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                            % Turn on the 2min timer
                            foodtimer = tic;
                            % Turn on port a tiiiiiny bit
                            deliver_reward_tiny_right(app);
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                            % Wait 2 minutes
                            while toc(foodtimer) < WaitTime % Timer less than 2 min
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp("Bat did not get reward.")
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R1AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                Box.Task.BatFailuresRight = Box.Task.BatFailuresRight + 1;
                            elseif FT < WaitTime
                                deliver_reward_B2(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R1AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end
                            pause(jitter);
                           
                        % To the left
                        %elseif i > 22
                        %elseif mod(i,2)==0
                        elseif ismember(i,rightfeederidx)
                            % Turn on the LED
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                            % Turn on the 2min timer
                            foodtimer = tic;
                            % Turn on port a tiiiiiny bit
                            deliver_reward_tiny_left(app);
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                            % Wait 2 minutes
                            while toc(foodtimer) < WaitTime % Timer less than 2 min
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp("Bat did not get reward.")
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L1AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                Box.Task.BatFailuresLeft = Box.Task.BatFailuresLeft + 1;
                            elseif FT < WaitTime
                                deliver_reward_B4(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L1AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end
                        end
                        pause(jitter);
                    end
                    
                    % Threshold number of failures.
                    if (Box.Task.BatFailuresRight + Box.Task.BatFailuresLeft)/45 < 0.2 
                        disp("Bat passes, enough successful trials to go on to stage 2")
                    else
                        disp("Bat remains at stage 1");
                    end                        
                    
                elseif app.LearningStageDropDown.Value == 'Stage 2'
                    disp("Beginning Stage 2. (RRRRRRRRRRRRRRRRRRRRRRRRRRRRRRRR)");
                    disp(strcat("Bat: ",Box.HLRBat));
                    WaitTime = app.TimedelaytoretrieverewardsEditField.Value;
                    % Initialize storeage of bat failures
                    Box.Task.BatFailuresRight = 0; Box.Task.BatFailuresLeft = 0;
                    rng(0,'twister');
                    b = app.JitterIntervalEditField.Value+10;
                    a = app.JitterIntervalEditField.Value-10;
                    rightfeedidx=[21,22,23];
                    rightfeedidx=[1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20];
                    for i=1:15
                        if strcmp(app.TaskSwitch.Value, 'Stop')
                            break;
                        end
                        % Random jitter
                        jitter=(b-a).*rand(1,1)+a;
                        % To the right
                        %if i < 22
                        if ismember(i,rightfeedidx)
                            % Turn on the LED
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                            % Turn on timer
                            foodtimer = tic;
                            % Get foodport status
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                            % While the bat is under the time allowed to
                            % get to the reward
                            disp("Waiting for bat to get reward Feeder #2...");
                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp(strcat("Bat did not get reward. Trial ",num2str(i)));
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                Box.Task.BatFailuresRight = Box.Task.BatFailuresRight + 1;
                            elseif FT < WaitTime
                                deliver_reward_B2(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end

                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R2AUTO, TriggerDateTime,TriggerTimeStamp, NaN,FoodPort,NaN);
                            end
                            pause(jitter);
                                
                        % To the left
                        %elseif i > 22
                        elseif ismember(i,leftfeedidx)
                            % Turn on the LED
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                            % Turn on timer
                            foodtimer = tic;
                            % Get foodport status
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                            % While the bat is under the time allowed to
                            % get to the reward
                            disp("Waiting for bat to get reward Feeder #4...");
                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp(strcat("Bat did not get reward. Trial ",num2str(i)));
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                Box.Task.BatFailuresLeft = Box.Task.BatFailuresLeft + 1;
                            elseif FT < WaitTime
                                deliver_reward_B4(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end

                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L2AUTO, TriggerDateTime,TriggerTimeStamp, NaN,FoodPort,NaN);
                            end
                            pause(jitter);
                        end
                    end
                        
                    % Threshold failures for moving to next round.
                    if Box.Task.BatFailuresRight+Box.Task.BatFailuresLeft/45 < 0.2 
                        disp("Bat passes, enough successful trials to go on to stage 3")
                    else
                        disp("Bat remains at stage 2");
                    end         
                    
                elseif app.LearningStageDropDown.Value == 'Stage 3'
                    disp("Beginning Stage 3 (RRR-LLL-repeat).");
                    disp(strcat("Bat: ",Box.HLRBat));
                    WaitTime = app.TimedelaytoretrieverewardsEditField.Value;
                    % Initialize storeage of bat failures
                    Box.Task.BatFailuresRight = 0; Box.Task.BatFailuresLeft = 0;
                    rng(0,'twister');
                    b = app.JitterIntervalEditField.Value+10;
                    a = app.JitterIntervalEditField.Value-10;
                    rightfeedidx=[1,3,5,7,9,11,13,15,17,19];
                    leftfeedidx=[2,4,6,8,10,12,14,16,18,20];
                    for i=1:15
                        if strcmp(app.TaskSwitch.Value, 'Stop')
                            break;
                        end
                        % Random jitter
                        jitter=(b-a).*rand(1,1)+a;
                        % To the right
                        %if i < 22
                        if ismember(i,rightfeedidx)
                            % Turn on the LED
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                            % Turn on timer
                            foodtimer = tic;
                            % Get foodport status
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                            % While the bat is under the time allowed to
                            % get to the reward
                            disp("Waiting for bat to get reward Feeder #2...");
                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp(strcat("Bat did not get reward. Trial ",num2str(i)));
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                Box.Task.BatFailuresRight = Box.Task.BatFailuresRight + 1;
                            elseif FT < WaitTime
                                deliver_reward_B2(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end

                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R2AUTO, TriggerDateTime,TriggerTimeStamp, NaN,FoodPort,NaN);
                            end
                            pause(jitter);
                                
                        % To the left
                        %elseif i > 22
                        elseif ismember(i,leftfeedidx)
                            % Turn on the LED
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                            % Turn on timer
                            foodtimer = tic;
                            % Get foodport status
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                            % While the bat is under the time allowed to
                            % get to the reward
                            disp("Waiting for bat to get reward Feeder #4...");
                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp(strcat("Bat did not get reward. Trial ",num2str(i)));
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                Box.Task.BatFailuresLeft = Box.Task.BatFailuresLeft + 1;
                            elseif FT < WaitTime
                                deliver_reward_B4(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L2AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end

                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L2AUTO, TriggerDateTime,TriggerTimeStamp, NaN,FoodPort,NaN);
                            end
                            pause(jitter);
                        end
                    end
                        
                    % Threshold failures for moving to next round.
                    if Box.Task.BatFailuresRight+Box.Task.BatFailuresLeft/45 < 0.2 
                        disp("Bat passes, enough successful trials to go on to stage 4")
                    else
                        disp("Bat remains at stage 3");
                    end  
                    
                elseif app.LearningStageDropDown.Value == 'Stage 4'  
                    disp("Beginning Stage 3.");
                    disp(strcat("Bat: ",Box.HLRBat));
                    WaitTime = app.TimedelaytoretrieverewardsEditField.Value;
                    % Look for what condition sequence you want and do that 
                    if app.CondSequenceDropDown.Value == 'Sequence 1'
                        ConditionSequence = {'2R','2R','2L','2L','3R','3R','3L','3L',...
                                             '2R','2R','2L','2L','3R','3R','3L','3L'};
                    % Do sequence 1 (48 conditions for now)
                    elseif app.CondSequenceDropDown.Value == 'Sequence 2'
                    % Do sequence 2 (48 conditions for now)
                    % REMEMBER > Condition 1 is Auto feed NO human.
                    % Condition 2 is Auto feed human.
                    % Condition 3 is human feed.
                        ConditionSequence = {'1R','2R','3R','1R','2R','3R','2R','3R',...
                                             '1L','2L','3L','1L','2L','3L','2L','3L'};
                    end
                    rng(0,'twister');
                    b = app.JitterIntervalEditField.Value+10;
                    a = app.JitterIntervalEditField.Value-10;
                    for i=1:length(ConditionSequence) % For 90 trials... (3 conditions, 15 of each on each side)
                        if strcmp(app.TaskSwitch.Value, 'Stop')
                            break;
                        end
                        % Jitter the time between trials 
                        jitter=(b-a).*rand(1000,1)+a;
                        % LEDCue2 is right; % LEDCue4 is left
                        % Keep track of failures in a table for the
                        % conditions:
                            % 1R 2R 3R
                            % 1L 2L 3L
                        SV = ConditionSequence{i};
                        % RIGHT, HAND-FED
                        if SV(2) == 'R' && SV(1) == '3'
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                            % Start timer
                            foodtimer = tic;
                            % While the bat is under the time allowed to
                            % get to the reward
                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp("Bat did not get reward.")
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R3HAND,TriggerDateTime,TriggerTimeStamp,FT);
                                % Log as failure
                                Box.Task.BatFailures(1,3) = Box.Task.BatFailures(1,3)+1;
                            elseif FT < WaitTime
                                deliver_reward_B2(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R3HAND,TriggerDateTime,TriggerTimeStamp,FT);
                            end
                            
                        % RIGHT, AUTO-FED
                        elseif SV(2) == 'R' && (SV(1) == '1' || SV(1) == '2')
                            writeDigitalPin(Box.Arduino, Box.LEDCue2, 1); % set the Cue LED on the given box to make them come to feeder
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~

                            % Start timer
                            foodtimer = tic;

                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatTwo); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp("Bat did not get reward.")
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R3AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                % Log as failure
                                if SV(1) == '2'
                                    Box.Task.BatFailures(1,2) = Box.Task.BatFailures(1,2)+1;
                                elseif SV(1) == '1'
                                    Box.Task.BatFailures(1,1) = Box.Task.BatFailures(1,1)+1;
                                end
                            elseif FT < WaitTime
                                deliver_reward_B2(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_R3AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end

                        % LEFT BY HAND
                        elseif SV(2) == 'L' && SV(1) == '3'
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~

                            % Start timer
                            foodtimer = tic;

                            while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp("Bat did not get reward.")
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L3HAND,TriggerDateTime,TriggerTimeStamp,FT);
                                % Log as failure
                                Box.Task.BatFailures(2,3) = Box.Task.BatFailures(2,3)+1;
                            elseif FT < WaitTime
                                deliver_reward_B4(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L3HAND,TriggerDateTime,TriggerTimeStamp,FT);
                            end 

                        elseif SV(2) == 'L' && (SV(1) == '1' || SV(1) == '2')
                            writeDigitalPin(Box.Arduino, Box.LEDCue4, 1); % set the Cue LED on the given box to make them come to feeder
                            FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~

                            % Start timer
                            foodtimer = tic;

                           while toc(foodtimer) < WaitTime % Timer less than WaitTime
                                FoodPort = ~readDigitalPin(Box.Arduino, Box.BeamFoodBatFour); % Pins are high at start, at break they are 0 --> read them with ~
                                if FoodPort+FoodPort_old == 1 %any food port broken after a break in food port activation?
                                    FT = toc(foodtimer);
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    disp(strcat(num2str(toc(foodtimer)),' ','seconds to feed.'));
                                    break;
                                elseif toc(foodtimer) > WaitTime
                                    FT = toc(foodtimer);
                                    disp("Bat did not get reward.")
                                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF'); % Take date stamp
                                    TriggerTimeStamp = toc(Box.Task.TimerTask); % Take time stamp
                                    break;
                                end
                            end
                            if FT > WaitTime
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % turn off Cue LED
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L3AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                                % Log as failure
                                if SV(1) == '2'
                                    Box.Task.BatFailures(2,2) = Box.Task.BatFailures(2,2)+1;
                                elseif SV(1) == '1'
                                    Box.Task.BatFailures(2,1) = Box.Task.BatFailures(2,1)+1;
                                end
                            elseif FT < WaitTime
                                deliver_reward_B4(app);
                                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0);
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_L3AUTO,TriggerDateTime,TriggerTimeStamp,FT);
                            end
                        end
                        pause(jitter);
                    end
                    fprintf('HLR Task is Over.\n')
                    fprintf('Waiting for Experimentor to End Recording...\n')
                    while Box.Task.HandFeedTerminate == 0
                        Box.Task.HandFeedTerminate = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand);
                    end
                end
                                        
                NewCheck = toc(Box.Task.TimerTask); 
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                % the following check make sure we're not goign into a re-entrancy error when the timer_AutoOff is running its call back function
                if app.AutomaticoffafterCheckBox.Value && ~isvalid(app.Timer_AutoOff)
                    break
                end
            end
            %fprintf('HLR Task is Over.\n')
            %fprintf('Waiting for Experimentor to End Recording...\n')
            %while Box.Task.HandFeedTerminate == 0
            %    Box.Task.HandFeedTerminate = ~readDigitalPin(Box.Arduino, Box.BeamFoodHand);
            %end
            stop_task(app);
        end
        
        function deliver_reward_B1(app)
            global Box
            %app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            fprintf(1,'*** Delivering reward to Bat #1 ***\n');
            pause(app.PausebeforerewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
            Box.dcm1.Speed = app.MotorspeedSpinner.Value; % Defining the speed of the syringe motor forward
            start(Box.dcm1); % initiate the forward movement of the motor
            pause(app.RewarddurationsEditField.Value); %amount of time to push motor forward
            stop(Box.dcm1); % stop the forward movement of the motor
            pause(app.PauseafterrewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
        end
        
        function deliver_reward_B2(app)
            global Box
            %app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            fprintf(1,'*** Delivering reward to Bat #2 (Stage Right) ***\n');
            pause(app.PausebeforerewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
            Box.dcm2.Speed = -(app.MotorspeedSpinner.Value); % Defining the speed of the syringe motor forward
            start(Box.dcm2); % initiate the forward movement of the motor
            pause(app.RewarddurationsEditField.Value); %amount of time to push motor forward
            stop(Box.dcm2); % stop the forward movement of the motor
            pause(app.PauseafterrewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
        end
        
        function deliver_reward_B3(app)
            global Box
            %app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            fprintf(1,'*** Delivering reward to Bat #3 ***\n');
            pause(app.PausebeforerewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
            Box.dcm3.Speed = -(app.MotorspeedSpinner.Value); % Defining the speed of the syringe motor forward
            start(Box.dcm3); % initiate the forward movement of the motor
            pause(app.RewarddurationsEditField.Value); %amount of time to push motor forward
            stop(Box.dcm3); % stop the forward movement of the motor
            pause(app.PauseafterrewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
        end
        
        function deliver_reward_B4(app)
            global Box
            %app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            fprintf(1,'*** Delivering reward to Bat #4 (Stage Left) ***\n');
            pause(app.PausebeforerewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
            Box.dcm4.Speed = app.MotorspeedSpinner.Value; % Defining the speed of the syringe motor forward
            start(Box.dcm4); % initiate the forward movement of the motor
            pause(app.RewarddurationsEditField.Value); %amount of time to push motor forward
            stop(Box.dcm4); % stop the forward movement of the motor
            pause(app.PauseafterrewardsEditField.Value) % Pausing the whole system for the amount of seconds requested  
        end
        
        function deliver_reward_tiny_right(app)
            global Box
            %app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            fprintf(1,'*** Delivering tiny reward Right Stage 1 learning ***\n');
            pause(app.PausebeforerewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
            Box.dcm2.Speed = -(app.MotorspeedSpinner.Value); % Defining the speed of the syringe motor forward
            start(Box.dcm2); % initiate the forward movement of the motor
            pause(0.1); % hard coded very small value (0.1)
            stop(Box.dcm2); % stop the forward movement of the motor
            pause(app.PauseafterrewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
        end
        
        function deliver_reward_tiny_left(app)
            global Box
            %app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            fprintf(1,'*** Delivering tiny reward Left Stage 1 learning ***\n');
            pause(app.PausebeforerewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
            Box.dcm4.Speed = app.MotorspeedSpinner.Value; % Defining the speed of the syringe motor forward
            start(Box.dcm4); % initiate the forward movement of the motor
            pause(0.1); %amount of time to push motor forward
            stop(Box.dcm4); % stop the forward movement of the motor
            pause(app.PauseafterrewardsEditField.Value) % Pausing the whole system for the amount of seconds requested
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Sess)
            % First clear the Box variable in case it's been created in previous runs of the app and not correctly deleted
            clearvars -global Box
            global Box
            % Set the box ID requested in input or take values given in the app
            if nargin<2
               Sess=input('Indicate the session number:\n');
            end
            
            % set the box number to the correct value
            app.BoxIDLabel.Text=sprintf('Sess%d',Sess);
            
            % Initiate a structure for the box
            Box.ID = 3;
            Box.Session = Sess;
            
            % Set up the email communication
            setpref('Internet','E_mail','madeleine.snyder.48@gmail.com');
            setpref('Internet','SMTP_Server','smtp.gmail.com');
            setpref('Internet','SMTP_Username','madeleine.snyder.48@gmail.com');
            setpref('Internet','SMTP_Password','Cutyptooty13;');
            % Gmail server.
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth','true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port','465');
            
            Box.Emails = {'madeleinesnyder@berkeley.edu'};
            
            %% initialize arduino
            [Box]=init_arduino(app, Box);
            
            %% initialize SoundMex
            [Box] = init_soundmex(app, Box);
            
            %% load initialization file for session info if there is one
            InitFile = dir('voc_Operant_init.txt');
            
        end

        % Button pushed function: FwdButton1
        function fwd1_button_pushed(app, event)
            global Box
            Box.dcm1.Speed = 1; % Defining the speed of the syringe motor forward
            start(Box.dcm1); % initiate the forward movement of the motor
            pause(1); %amount of time to push motor forward
            stop(Box.dcm1); % stop the forward movement of the motor
        end

        % Button pushed function: BwdButton1
        function bwd1_button_pushed(app, event)
            global Box
            Box.dcm1.Speed = -1; % Defining the speed of the syringe motor backward
            start(Box.dcm1); % initiate the backward movement of the motor
            pause(1); %amount of time to push motor backward
            stop(Box.dcm1); % stop the backward movement of the motor
        end

        % Button pushed function: Reset1Button
        function reset1_button_pushed(app, event)
            global Box
            Box.dcm1.Speed = -1; % Defining the speed of the syringe motor backward
            start(Box.dcm1); % initiate the backward movement of the motor
            pause(29); %amount of time to push motor backward in initial position
            stop(Box.dcm1); % stop the backward movement of the motor
        end

        % Button pushed function: Cue1Button
        function cue1_LED_flash(app, event)
            global Box
            for tt=1:2
                writeDigitalPin(Box.Arduino, Box.LEDCue1, 1);
                pause(0.5)
                writeDigitalPin(Box.Arduino, Box.LEDCue1, 0);
                pause(0.5)
            end
        end

        % Button pushed function: Cue2Button
        function cue2_LED_flash(app, event)
            global Box
            for tt=1:2
                writeDigitalPin(Box.Arduino, Box.LEDCue2, 1);
                pause(0.5)
                writeDigitalPin(Box.Arduino, Box.LEDCue2, 0);
                pause(0.5)
            end
        end

        % Button pushed function: PlayButton
        function play_button_pushed(app, event)
            global Box
            Filename = app.PlayListBox.Value{1};
            % Check the sample frequency of the file, it should be the same as the Motu
            [~, fs] = audioread(Filename);
            if fs~=Box.SoundCard.fs
                error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
            else
                soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',3);
                soundmexpro('start', 'length', -1); % length=-1 ensure soundmex is off after sound is played
            end
        end

        % Button pushed function: UpdatefilelistButton
        function update_file_list_pushed(app, event)
            global Box
            %% List the files in the playback folder
            Files = dir(fullfile(Box.PlayPath, '*.wav'));
            app.PlayListBox.ItemsData = cell(length(Files),1);
            for ff=1:length(Files)
                app.PlayListBox.Items{ff} = Files(ff).name;
                app.PlayListBox.ItemsData{ff} = fullfile(Box.PlayPath, Files(ff).name);
            end
        end

        % Value changed function: Detect
        function detect_sound_mic(app, event)
            global Box
            % Freeze input parameters
            disable_sound_detection_inputs(app);
            
            % Get parameters from app and set the threshold amplitude and the duration of the sample that should reach...
            % that threshold for sound to be detected on input recordings
            [NumBufClip,Filter,RmsThresh] = set_sound_detection_parameters(app, Box);
            
            % run a function for RecDur sec that detects sound and lights up the LED Cue when it does
            RecDur=app.DurationsEditField.Value;
            soundmexpro('start', 'length', (RecDur+1)*Box.SoundCard.fs); % enable soumdmexpro recording and playback for RecDur +1 seconds
            app.MicLamp.Color = [1 0 0]; % Set the lamp app color to Red
            Timer=tic;
            while toc(Timer)<RecDur
                [success, ~] = soundDetect(app,Box,NumBufClip, Filter, RmsThresh);
                writeDigitalPin(Box.Arduino, Box.LEDCue1, success);
            end
            clear Timer
            writeDigitalPin(Box.Arduino, Box.LEDCue1, 0);
            app.MicLamp.Color = [0 0 0]; % Set back the lamp app color to Black
            enable_sound_detection_inputs(app);
            app.Detect.Value = 0;
        end

        % Value changed function: TaskSwitch
        function task_switch_action(app, event)
            value = app.TaskSwitch.Value;
            global Box
            if strcmp(value, 'Start')
                app.Lamp.Color = [0 1 0];
                app.Status_label.Text = 'Loading';
                %% Set values of parameters common to all protocols
                % Disable all inputs
                disable_manual_control_buttons(app);
                disable_metadata_inputs(app);
                disable_session_parameters_inputs(app);
                disable_sound_detection_inputs(app);
                disable_playback_parameters_inputs(app);
                disable_reward_parameters_input(app);
                disable_punishment_parameters_input(app);
                
                % Timeline of the experiment (limit on experiment duration, any break
                % requested...)
                if app.AutomaticoffafterCheckBox.Value
                    % create a timer to stop the experiment after the requested time
                    app.Timer_AutoOff = timer;
                    app.Timer_AutoOff.Name = 'AutoOff-timer';
                    app.Timer_AutoOff.Timerfcn = {@app.stop_task};
%                     app.Timer_AutoOff.StopFcn = @(x,y)disp(' ********* Reaching automatic stop time! ***********');
                    app.Timer_AutoOff.ExecutionMode = 'singleShot';
                    app.Timer_AutoOff.BusyMode = 'queue';
                    app.Timer_AutoOff.StartDelay = app.DurationhoursEditField.Value*3600; % duration of the task transform from hours to seconds
                    start(app.Timer_AutoOff);
                end
                
                if app.EnforcesleephoursCheckBox.Value
                    % create two timers, one to stop the experiment at the indicated time
                    % and another timer to wake-up the bats the following morning
                    error('Enforcing sleep hours feature is not available yet :-(\n')
                end
                
                
                %% Sound detection parameters (detection thresholds and possible
                % acoustic properties requirements if requested)
                % Get parameters from app and set the threshold amplitude and the duration of the sample that should reach...
                % that threshold for sound to be detected on input recordings
                [Box.Task.NumBufClip,Box.Task.Filter,Box.Task.RmsThresh] = set_sound_detection_parameters(app, Box);
                
                % if no output uncheked, initialize parameter file TTL pulses...
                if ~app.NooutputCheckBox.Value
                    init_task(app, app.TaskDropDown.Value)
                end
                
                %% Get the task that was selected and start the corresponding function
                if strcmp(app.TaskDropDown.Value, 'RecOnly')
                    rec_only_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'FeedTask')
                    feeding_phase_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'HLRTask')
                    HLR_task(app)
                end

                
                % Reward parameters (delay to go to the food port upon triger, duration
                % of reward, motor syringe speed)


                % ProductionReward: every vocalization of the bat is rewarded. If the
                % detected call reach thersholds and, if requested, hit any requirements in terms of
                % acoustic properties, then the LED cue turns on and the bat has xs
                % (MaxDelay) to come at the feedport and break the food beam to get a
                % reward
                
            elseif strcmp(value, 'Stop')
                fprintf('we are using the stop switch\n')
                stop_task(app)
            end
            
        end

        % Callback function: MetadataPanel, UpdateDataListButton
        function update_data_list_pushed(app, event)
            global Box
            %% List the files in the playback folder
            Files = dir(fullfile(Box.RecPath, '*_param.txt'));
            app.SelectDataDropDown.ItemsData = cell(length(Files),1);
            for ff=1:length(Files)
                app.SelectDataDropDown.Items{ff} = Files(ff).name;
                app.SelectDataDropDown.ItemsData{ff} = fullfile(Box.RecPath, Files(ff).name);
            end
        end

        % Button pushed function: UpdategraphButton
        function update_graph_pushed(app, event)
            % initialize the structure that will contain graph data
            app.GraphData = struct();
            % Get the recording data
            [DataPath, DataFile ,~]=fileparts(app.SelectDataDropDown.Value);
            WavFileStruc = dir(fullfile(DataPath, [DataFile(1:16) '*mic*.wav']));
            
            % Get the sound snippets from the sounds that triggered detection
            DataSnipStruc = dir(fullfile(DataPath, [DataFile(1:16) '*snippets/*.wav']));
            
            % Get the sample stamp of the detected vocalizations
            DataFileStruc = dir(fullfile(DataPath, [DataFile(1:16) '*events.txt']));
            Fid_Data = fopen(fullfile(DataFileStruc.folder,DataFileStruc.name));
            app.GraphData.EventsHeader = textscan(Fid_Data, '%s\t%s\t%s\t%s\t%s\t%s\t%s\n',1);
            for hh=1:length(app.GraphData.EventsHeader)
                if strfind(app.GraphData.EventsHeader{hh}{1}, 'SampleStamp')
                    app.GraphData.EventsStampCol = hh;
                elseif strfind(app.GraphData.EventsHeader{hh}{1}, 'Type')
                    app.GraphData.EventsEventTypeCol = hh;
                elseif strfind(app.GraphData.EventsHeader{hh}{1}, 'FoodPortFront')
                    app.GraphData.EventsFoodPortFrontCol = hh;
                elseif strfind(app.GraphData.EventsHeader{hh}{1}, 'FoodPortBack')
                    app.GraphData.EventsFoodPortBackCol = hh;
                end
            end
            app.GraphData.Events = textscan(Fid_Data, '%s\t%f\t%s\t%s\t%f\t%f\t%f');
            fclose(Fid_Data);
            VocId = find(strcmp('Vocalization', app.GraphData.Events{app.GraphData.EventsEventTypeCol}));
            
            
            % Calculate the offset of each soundfile output from the begining of the task
            app.GraphData.Offset_Y = zeros(length(WavFileStruc),1);
            for yy=2:length(WavFileStruc)
                Wavefile_local = fullfile(WavFileStruc(yy).folder, WavFileStruc(yy).name);
                [Y,FS] = audioread(Wavefile_local);
                % find the first snip of that sequence
                for ss=1:length(DataSnipStruc)
                    IndStamp1 = strfind(DataSnipStruc(ss).name, '_');
                    IndStamp_last = IndStamp1(end);
                    Sequence = str2double(DataSnipStruc(ss).name(IndStamp1(end-1)+1));
                    if Sequence == yy
                        [Ysnip,~] = audioread(fullfile(DataSnipStruc(ss).folder, DataSnipStruc(ss).name));
                        Stamp = str2double(DataSnipStruc(ss).name((IndStamp_last+1):end-3));
                        break
                    end
                end
                
                % Align that snip with the wav of the sequence and get the stamp of the snip
                C= normxcorr2(Ysnip, Y);
                Alligned = find(C==max(C));% This is the last sample that alligned to Ysnip in Y
                app.GraphData.Offset_Y(yy) = Stamp + length(Ysnip) -1 - Alligned; % Stamp is the position of the first sample of Ysnip
            end
            
            % Update the dropdown menu with all the detected calls
            FullStamps = app.GraphData.Events{app.GraphData.EventsStampCol}(VocId);
            if ~exist('FS', 'var')
                [~,FS] = audioread(fullfile(WavFileStruc(1).folder, WavFileStruc(1).name));
            end
            for ss=1:length(FullStamps)
                Ind_ = strfind(FullStamps{ss}, '_');
                Stamp = str2double(FullStamps{ss}((Ind_+1):end));
                MinStamp = floor(Stamp/(60*FS));
                SecStamp = (Stamp - (MinStamp*60*FS))/FS;
                app.SoundtimeDropDown.Items{ss} = sprintf('%d %dmin %.1fs', ss, MinStamp, SecStamp);
                app.SoundtimeDropDown.ItemsData{ss} = FullStamps{ss};
            end
            
            % call the callback function of the dropdown menu to plot the 2min around the first call detected
            update_graph_section(app,app.SoundtimeDropDown.Value)
            
        end

        % Button pushed function: TestFoodBatFour, TestFoodBatOne, 
        % TestFoodBatThree, TestFoodBatTwo, TestHand
        function detect_trigger_BB(app, event)
            global Box
            % Identify which Beam break we are testing
            if strcmp(event.Source.Text, 'Test Bat 1')
                BeamID = Box.BeamFoodBatOne;
            elseif strcmp(event.Source.Text, 'Test Bat 2')
                BeamID = Box.BeamFoodBatTwo;
            elseif strcmp(event.Source.Text, 'Test Bat 3')
                BeamID = Box.BeamFoodBatThree;
            elseif strcmp(event.Source.Text, 'Test Bat 4')
                BeamID = Box.BeamFoodBatFour;
            elseif strcmp(event.Source.Text, 'Test Hand')
                BeamID = Box.BeamFoodHand;
            else
                error('Unidentified beambreak name in detect_trigger_BB: %s\n',event.Source.Text)
            end
            % light up the cue LED (value set to 1) each time the beam is broken (value sent to 0) for RecDur seconds
            RecDur=25;%app.DurationsEditField.Value;
            app.BBLamp.Color = [1 0 0]; % set the LED for the triggers to red
            Timer=tic;
            while toc(Timer)<RecDur
                if BeamID == Box.BeamFoodBatOne
                    writeDigitalPin(Box.Arduino, Box.LEDCue1, ~readDigitalPin(Box.Arduino, BeamID));
                    disp(toc(Timer));
                    disp(~readDigitalPin(Box.Arduino, BeamID));
                elseif BeamID == Box.BeamFoodBatTwo
                    writeDigitalPin(Box.Arduino, Box.LEDCue2, ~readDigitalPin(Box.Arduino, BeamID));
                    disp(toc(Timer));
                    disp(~readDigitalPin(Box.Arduino, BeamID));
                elseif BeamID == Box.BeamFoodBatThree
                    writeDigitalPin(Box.Arduino, Box.LEDCue3, ~readDigitalPin(Box.Arduino, BeamID));
                    disp(toc(Timer));
                    disp(~readDigitalPin(Box.Arduino, BeamID));
                elseif BeamID == Box.BeamFoodBatFour
                    writeDigitalPin(Box.Arduino, Box.LEDCue4, ~readDigitalPin(Box.Arduino, BeamID));
                    disp(toc(Timer));
                    disp(~readDigitalPin(Box.Arduino, BeamID));
                elseif BeamID == Box.BeamFoodHand %have hand light up LED 4 for now
                    writeDigitalPin(Box.Arduino, Box.LEDCue4, ~readDigitalPin(Box.Arduino, BeamID));
                    disp(toc(Timer));
                    disp(~readDigitalPin(Box.Arduino, BeamID));
                end
            end
            clear Timer
            writeDigitalPin(Box.Arduino, Box.LEDCue1, 0); % Make sure LED Cue set back to 0
            writeDigitalPin(Box.Arduino, Box.LEDCue2, 0); % Make sure LED Cue set back to 0
            writeDigitalPin(Box.Arduino, Box.LEDCue3, 0); % Make sure LED Cue set back to 0
            writeDigitalPin(Box.Arduino, Box.LEDCue4, 0); % Make sure LED Cue set back to 0
            app.BBLamp.Color = [0 0 0]; % set the LED for the triggers to black
        end

        % Value changed function: XmaxEditField, XminEditField
        function update_graph_xlim(app, event)
            global Box
            app.UIAxes.XLim(1)=app.XminEditField.Value*Box.SoundCard.fs;
            app.UIAxes.XLim(2)=app.XmaxEditField.Value*Box.SoundCard.fs;
            LengthY = app.UIAxes.XLim(2) - app.UIAxes.XLim(1);
            if (LengthY/Box.SoundCard.fs)>app.UIAxes.UserData.Res
                app.UIAxes.UserData.Step = round(LengthY/(Box.SoundCard.fs*app.UIAxes.UserData.Res));
            else
                app.UIAxes.UserData.Res=10;
                app.UIAxes.UserData.Step = round(LengthY/(Box.SoundCard.fs*app.UIAxes.UserData.Res),2);
            end
            app.UIAxes.XTick = app.UIAxes.XLim(1):(app.UIAxes.UserData.Step*Box.SoundCard.fs):app.UIAxes.XLim(2);
            app.UIAxes.XTickLabel = round(app.UIAxes.XLim(1)/Box.SoundCard.fs):app.UIAxes.UserData.Step:round(app.UIAxes.XLim(2)/Box.SoundCard.fs);
        end

        % Button pushed function: QUITButton
        function quit_voc_operant(app, event)
            clear soundmexpro
            clearvars -global Box
        end

        % Value changed function: SoundtimeDropDown
        function update_graph_section(app, event)
            global Box
            Ind_ = strfind(app.SoundtimeDropDown.Value, '_');
            Seq = str2double(app.SoundtimeDropDown.Value(1:(Ind_-1)));
            Stamp = str2double(app.SoundtimeDropDown.Value((Ind_+1):end));
            
            % Get the recording data for the corresponding sequence
            [DataPath, DataFile ,~]=fileparts(app.SelectDataDropDown.Value);
            WavFileStruc = dir(fullfile(DataPath, sprintf('%s*mic*_%d.wav',DataFile(1:16), Seq)));
            try
                Wavefile_local = fullfile(WavFileStruc.folder, WavFileStruc.name);
                [Y,FS] = audioread(Wavefile_local);
            catch
                fprintf(1,'Warning: the audiofile %s cannot be read properly and will not be plotted\n', Wavefile_local);
                Y = 0;
                FS=Box.SoundCard.fs;
            end
            Y_section_beg = max(1,Stamp - app.GraphData.Offset_Y(Seq) - 60*FS); % Make sure we don't request before the beginning of the section
            Pre_stamp = min(60*FS, Stamp - app.GraphData.Offset_Y(Seq));
            Y_section_end = min(length(Y), Stamp - app.GraphData.Offset_Y(Seq) + 60*FS); % Make sure we don't request after the beginning of the section
            Post_stamp = min(60*FS, length(Y)- (Stamp - app.GraphData.Offset_Y(Seq)));
            Y_section = Y(Y_section_beg:Y_section_end);
            
            % Plot the waveforms of the recording around the stamp of the vocalization
            cla(app.UIAxes)
            if ~ishold(app.UIAxes)
                hold(app.UIAxes)
            end
            plot(app.UIAxes, Y_section, 'Color', 'k')
            
            
            % plot the sound snippets in red on top
            % Get the sound snippets from the sounds that triggered detection
            DataSnipStruc = dir(fullfile(DataPath, sprintf('%s*snippets/*snipfile_%d*.wav', DataFile(1:16), Seq)));
            VocId = find(strcmp('Vocalization', app.GraphData.Events{app.GraphData.EventsEventTypeCol}));
            
            SnipDur = nan(length(VocId),1);
            Stamp_all = nan(length(VocId),1);
            VocId_all = nan(length(VocId),1);
            Voc_event_nb = 0;
            for ss=1:length(DataSnipStruc)
                IndStamp1 = strfind(DataSnipStruc(ss).name, '_');
                IndStamp_last = IndStamp1(end);
                if str2double(DataSnipStruc(ss).name((IndStamp_last+1) : (end-4))) < (Stamp + Post_stamp)
                    [Ysnip,FS] = audioread(fullfile(DataSnipStruc(ss).folder, DataSnipStruc(ss).name));
                    Stamp_local = str2double(DataSnipStruc(ss).name((IndStamp_last+1):end-3));
                    Stamp_local_context = Stamp_local - Stamp + Pre_stamp;
                    Sequence = str2double(DataSnipStruc(ss).name(IndStamp1(end-1)+1));
                    Voc_event_nb = Voc_event_nb + 1;
                    plot(app.UIAxes, Stamp_local_context:(Stamp_local_context+length(Ysnip)-1), Ysnip, 'Color', 'r')
                    % Search for the corresponding datapoint in the event log
                    Index =[];
                    for ii=1:length(VocId)
                        if strcmp(app.GraphData.Events{app.GraphData.EventsStampCol}{VocId(ii)}, sprintf('%d_%d', Sequence, Stamp_local))
                            Index = ii;
                            break
                        end
                    end
                    if isempty(Index)
                        error('event %d_%d cannot be found in the eventlog file\n', Sequence, Stamp_local);
                    else
                        Stamp_all(Voc_event_nb) = Stamp_local;
                        SnipDur(Voc_event_nb) = length(Ysnip);
                        VocId_all(Voc_event_nb) = VocId(Index);
                    end
                end
            end
            
            % plot the time stamp when sound was detected (last time point of each sound snippet)
            % Get the VocId should be plotted, from the log
            VocId_log = nan(1,length(VocId));
            Stamp_all_log = nan(1,length(VocId));
            SnipDur_log = nan(1,length(VocId));
            Voc_event_nb_log = 0;
            for ii=1:length(VocId)
                FullStamp = app.GraphData.Events{app.GraphData.EventsStampCol}{VocId(ii)};
                IndStamp = strfind(FullStamp, '_');
                Seq_local = str2double(FullStamp(1:(IndStamp-1)));
                Stamp_local = str2double(FullStamp((IndStamp+1):end));
                if Seq_local == Seq && (Stamp_local >= (Stamp-Pre_stamp)) && (Stamp_local <= (Stamp+Post_stamp)) % This is a vocalization from the same section
                    Voc_event_nb_log = Voc_event_nb_log+1;
                    VocId_log(Voc_event_nb_log) = VocId(ii);
                    Stamp_all_log(Voc_event_nb_log) = Stamp_local;
                    try
                        SnipDur_log(Voc_event_nb_log) = SnipDur(find(VocId_all==VocId(ii)));
                    catch
                        warning('This sound detection event does not have a corresponding sound snippet %s',FullStamp);
                        SnipDur_log(Voc_event_nb_log) = NaN;
                    end
                end
            end
            VocId_log = VocId_log(1:Voc_event_nb_log);
            Stamp_all_log = Stamp_all_log(1:Voc_event_nb_log);
            SnipDur_log = SnipDur_log(1:Voc_event_nb_log);
            
            ReFront = app.GraphData.Events{app.GraphData.EventsFoodPortFrontCol}(VocId_log);
            ReBack = app.GraphData.Events{app.GraphData.EventsFoodPortBackCol}(VocId_log);
            Colorcode = get(groot,'DefaultAxesColorOrder');
            ColorFront= nan(size(ReFront,1),3);
            ColorBack= ColorFront;
            ColorFront(find(ReFront==1),:) = repmat(Colorcode(3,:),sum(ReFront==1),1);
            ColorBack(find(ReBack==1),:) = repmat(Colorcode(3,:), sum(ReBack==1),1);
            ColorFront(find(ReFront==0),:) = repmat([0 0 0],sum(ReFront==0),1);
            ColorBack(find(ReBack==0),:) = repmat([0 0 0], sum(ReBack==0),1);
            ColorFront(find(isnan(ReFront)),:) = repmat([1 0 0],sum(isnan(ReFront)),1);
            ColorBack(find(isnan(ReBack)),:) = repmat([1 0 0], sum(isnan(ReBack)),1);
            sc=scatter(app.UIAxes, Stamp_all_log+SnipDur_log-1, 1.2*ones(length(VocId_log),1), 10, ColorFront, 'filled');
            sc=scatter(app.UIAxes, Stamp_all_log+SnipDur_log-1, 1.6*ones(length(VocId_log),1), 10, ColorBack, 'filled');
            hold(app.UIAxes)
            
            % let's fix the number of xticks to 20
            app.UIAxes.UserData=struct();
            app.UIAxes.UserData.Res=20;
            app.UIAxes.XLim(1) = 0;
            LengthY = length(Y_section);
            app.UIAxes.XLim(2) = LengthY;
            app.XminEditField.Value = app.UIAxes.XLim(1)/FS;
            app.XmaxEditField.Value = app.UIAxes.XLim(2)/FS;
            if (LengthY/FS)>app.UIAxes.UserData.Res
                app.UIAxes.UserData.Step = round(LengthY/(FS*app.UIAxes.UserData.Res));
            else
                app.UIAxes.UserData.Step = round(LengthY/(FS*app.UIAxes.UserData.Res),2);
            end
            app.UIAxes.XTick = 0:(app.UIAxes.UserData.Step*FS):LengthY;
            app.UIAxes.XTickLabel = 0:app.UIAxes.UserData.Step:round(LengthY/FS);
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = 'Sound pressure';
            app.UIAxes.YLim = [-1 2];
        end

        % Button pushed function: CalibratePlayBackGainButton
        function CalibratePlayBackGainButtonPushed(app, event)
            global Box
            Compensation = app.CompensationCheckBox.Value;
            if Compensation
                %load the impulse response calculated for the speaker
                SPFile = dir(fullfile(Box.RecPath,'AudioCompensation/Output/Comp_IR_scanspeak*.mat'));
                ii=1;
                if length(SPFile)>1
                    for ii=1:length(SPFile)
                        fprintf('%d %s\n', ii, SPFile(ii).name)
                    end
                    input('Indicate which impulse response file should be used for calibration:\n')
                elseif isempty(SPFile)
                    error('First create a impulse response file by using the calibration station on the intact recording chain\nThe filename should start by Comp_IR_scanspeak and be placed in:\n%s\n',fullfile(Box.RecPath,'AudioCompensation/Output/'));
                end
                SP = load(fullfile(SPFile(ii).folder,SPFile(ii).name));
    
                % load the impulse response of the microphone
                MicFile = dir(fullfile(Box.RecPath,'AudioCompensation/Output/Comp_IR_earthworks*.mat'));
                ii=1;
                if length(MicFile)>1
                    for ii=1:length(MicFile)
                        fprintf('%d %s\n', ii, MicFile(ii).name)
                    end
                    input('Indicate which impulse response file should be used for calibration:\n')
                elseif isempty(MicFile)
                    error('First create a impulse response file by using the calibration station on the intact recording chain\nThe filename should start by Comp_IR_scanspeak and be placed in:\n%s\n',fullfile(Box.RecPath,'AudioCompensation/Output/'));
                end
                Mic = load(fullfile(MicFile(ii).folder,MicFile(ii).name));
            else
                Mic=[];
                SP = [];
            end

            % load the recording used for calibration (a wav file that you create by extracting a recording made in the box)
            CalibFile = dir(fullfile(Box.RecPath,'AudioCompensation/RecCalib*'));
            ii=1;
            if length(CalibFile)>1
                for ii=1:length(CalibFile)
                    fprintf('%d %s\n', ii, CalibFile(ii).name)
                end
                input('Indicate which file should be used for calibration:\n')
            elseif isempty(CalibFile)
                error('Firts create a calibration file by recording any sound in the box using the intact recording chain\nThe filename should start by RecCalib and be placed in:\n%s\n',fullfile(Box.RecPath,'AudioCompensation/'));
            end
            [In,Fs] = audioread(fullfile(CalibFile(ii).folder, CalibFile(ii).name));
            
            % Start searching for the right gain. Set the initial gain to 1
            Gain_in = 1;
            findgainsoundmex(app, Box, In,Fs, Mic, SP, Gain_in, Compensation);
        end

        % Button pushed function: Cue3Button
        function cue3_LED_flash(app, event)
            global Box
            for tt=1:2
                writeDigitalPin(Box.Arduino, Box.LEDCue3, 1);
                pause(0.5)
                writeDigitalPin(Box.Arduino, Box.LEDCue3, 0);
                pause(0.5)
            end
        end

        % Button pushed function: Cue4Button
        function cue4_LED_flash(app, event)
            global Box
            for tt=1:2
                writeDigitalPin(Box.Arduino, Box.LEDCue4, 1);
                pause(0.5)
                writeDigitalPin(Box.Arduino, Box.LEDCue4, 0);
                pause(0.5)
            end
        end

        % Button pushed function: Reset2Button
        function reset2_button_pushed(app, event)
            global Box
            Box.dcm2.Speed = 1; % Defining the speed of the syringe motor backward
            start(Box.dcm2); % initiate the backward movement of the motor
            pause(29); %amount of time to push motor backward in initial position
            stop(Box.dcm2); % stop the backward movement of the motor
        end

        % Button pushed function: Reset3Button
        function reset3_button_pushed(app, event)
            global Box
            Box.dcm3.Speed = 1; % Defining the speed of the syringe motor backward
            start(Box.dcm3); % initiate the backward movement of the motor
            pause(29); %amount of time to push motor backward in initial position
            stop(Box.dcm3); % stop the backward movement of the motor
        end

        % Button pushed function: Reset4Button
        function reset4_button_pushed(app, event)
            global Box
            Box.dcm4.Speed = -1; % Defining the speed of the syringe motor backward
            start(Box.dcm4); % initiate the backward movement of the motor
            pause(29); %amount of time to push motor backward in initial position
            stop(Box.dcm4); % stop the backward movement of the motor
        end

        % Button pushed function: FwdButton2
        function fwd2_button_pushed(app, event)
            global Box
            Box.dcm2.Speed = -1; % Defining the speed of the syringe motor forward
            start(Box.dcm2); % initiate the forward movement of the motor
            pause(1); %amount of time to push motor forward
            stop(Box.dcm2); % stop the forward movement of the motor
        end

        % Button pushed function: FwdButton3
        function fwd3_button_pushed(app, event)
            global Box
            Box.dcm3.Speed = -1; % Defining the speed of the syringe motor forward
            start(Box.dcm3); % initiate the forward movement of the motor
            pause(1); %amount of time to push motor forward
            stop(Box.dcm3); % stop the forward movement of the motor
        end

        % Button pushed function: FwdButton4
        function fwd4_button_pushed(app, event)
            global Box
            Box.dcm4.Speed = 1; % Defining the speed of the syringe motor forward
            start(Box.dcm4); % initiate the forward movement of the motor
            pause(1); %amount of time to push motor forward
            stop(Box.dcm4); % stop the forward movement of the motor
        end

        % Button pushed function: BwdButton2
        function bwd2_button_pushed(app, event)
            global Box
            Box.dcm2.Speed = 1; % Defining the speed of the syringe motor backward
            start(Box.dcm2); % initiate the backward movement of the motor
            pause(1); %amount of time to push motor backward
            stop(Box.dcm2); % stop the backward movement of the motor
        end

        % Button pushed function: BwdButton3
        function bwd3_button_pushed(app, event)
            global Box
            Box.dcm3.Speed = 1; % Defining the speed of the syringe motor backward
            start(Box.dcm3); % initiate the backward movement of the motor
            pause(1); %amount of time to push motor backward
            stop(Box.dcm3); % stop the backward movement of the motor
        end

        % Button pushed function: BwdButton4
        function bwd4_button_pushed(app, event)
            global Box
            Box.dcm4.Speed = -1; % Defining the speed of the syringe motor backward
            start(Box.dcm4); % initiate the backward movement of the motor
            pause(1); %amount of time to push motor backward
            stop(Box.dcm4); % stop the backward movement of the motor
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MetadataPanel = uipanel(app.UIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.MetadataPanel.SizeChangedFcn = createCallbackFcn(app, @update_data_list_pushed, true);
            app.Bat1Label = uilabel(app.MetadataPanel);
            app.Bat1Label.Text = 'Bat #1';
            app.BatOne = uieditfield(app.MetadataPanel, 'text');
            app.BatOne.Value = 'Clementine';
            app.Bat2Label = uilabel(app.MetadataPanel);
            app.Bat2Label.Text = 'Bat #2';
            app.BatTwo = uieditfield(app.MetadataPanel, 'text');
            app.BatTwo.Value = 'Ovaltine';
            app.Bat3EditFieldLabel = uilabel(app.MetadataPanel);
            app.Bat3EditFieldLabel.Text = 'Bat #3';
            app.BatThree = uieditfield(app.MetadataPanel, 'text');
            app.BatThree.Value = 'Saltine';
            app.Bat4EditFieldLabel = uilabel(app.MetadataPanel);
            app.Bat4EditFieldLabel.Text = 'Bat #4';
            app.BatFour = uieditfield(app.MetadataPanel, 'text');
            app.BatFour.Value = 'Valentine';
            app.FeedSequenceDropDownLabel = uilabel(app.MetadataPanel);
            app.FeedSequenceDropDownLabel.Text = 'Feed Sequence';
            app.FeedSequenceDropDown = uidropdown(app.MetadataPanel);
            app.FeedSequenceDropDown.Items = {'M', 'A', 'A12', 'A34', 'H12', 'H34', 'TH', 'TA'};
            app.FeedSequenceDropDown.ItemsData = {'M', 'A', 'A12', 'A34', 'H12', 'H34', 'TH', 'TA'};
            app.FeedSequenceDropDown.Value = 'M';
            app.LearningStageLabel = uilabel(app.MetadataPanel);
            app.LearningStageLabel.Text = 'Learning Stage';
            app.LearningStageDropDown = uidropdown(app.MetadataPanel);
            app.LearningStageDropDown.Items = {'Stage 1', 'Stage 2', 'Stage 3', 'Stage 4'};
            app.LearningStageDropDown.Value = 'Stage 1';
            app.CondSequenceDropDownLabel = uilabel(app.MetadataPanel);
            app.CondSequenceDropDownLabel.Text = 'Cond. Sequence';
            app.CondSequenceDropDown = uidropdown(app.MetadataPanel);
            app.CondSequenceDropDown.Items = {'Sequence 1', 'Sequence 2'};
            app.CondSequenceDropDown.Value = 'Sequence 1';
            app.BatGroup = uibuttongroup(app.MetadataPanel);
            app.Bat1 = uiradiobutton(app.BatGroup);
            app.Bat1.Text = '';
            app.Bat1.Value = true;
            app.Bat2 = uiradiobutton(app.BatGroup);
            app.Bat2.Text = '';
            app.Bat3 = uiradiobutton(app.BatGroup);
            app.Bat3.Text = '';
            app.Bat4 = uiradiobutton(app.BatGroup);
            app.Bat4.Text = '';
            app.TaskPanel = uipanel(app.UIFigure);
            app.TaskPanel.Title = 'Task';
            app.TaskSwitch = uiswitch(app.TaskPanel, 'rocker');
            app.TaskSwitch.Items = {'Stop', 'Start'};
            app.TaskSwitch.ValueChangedFcn = createCallbackFcn(app, @task_switch_action, true);
            app.TaskSwitch.Value = 'Stop';
            app.TaskDropDown = uidropdown(app.TaskPanel);
            app.TaskDropDown.Items = {'Record Only', 'Feeding Phase Task', 'HLR Task'};
            app.TaskDropDown.ItemsData = {'RecOnly', 'FeedTask', 'HLRTask'};
            app.TaskDropDown.Value = 'RecOnly';
            app.Lamp = uilamp(app.TaskPanel);
            app.Status_label = uilabel(app.TaskPanel);
            app.Status_label.Text = 'Status';
            app.NooutputCheckBox = uicheckbox(app.TaskPanel);
            app.NooutputCheckBox.Text = 'No output';
            app.TransceiverSyncCheckBox = uicheckbox(app.TaskPanel);
            app.TransceiverSyncCheckBox.Text = 'Transceiver Sync';
            app.TTLBaslerCheckBox = uicheckbox(app.TaskPanel);
            app.TTLBaslerCheckBox.Text = 'TTL Basler';
            app.TTLBaslerCheckBox.Value = true;
            app.QUITButton = uibutton(app.UIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @quit_voc_operant, true);
            app.QUITButton.Text = 'QUIT';
            app.ParametersTab = uitabgroup(app.UIFigure);
            app.SessionTab = uitab(app.ParametersTab);
            app.SessionTab.Title = 'Session';
            app.DurationhoursEditFieldLabel = uilabel(app.SessionTab);
            app.DurationhoursEditFieldLabel.Text = 'Duration (hours)';
            app.DurationhoursEditField = uieditfield(app.SessionTab, 'numeric');
            app.DurationhoursEditField.Limits = [0 Inf];
            app.DurationhoursEditField.Value = 4;
            app.AutomaticoffafterCheckBox = uicheckbox(app.SessionTab);
            app.AutomaticoffafterCheckBox.Text = 'Automatic off after:';
            app.AutomaticoffafterCheckBox.Value = true;
            app.EnforcesleephoursCheckBox = uicheckbox(app.SessionTab);
            app.EnforcesleephoursCheckBox.Text = 'Enforce sleep hours';
            app.FromEditFieldLabel = uilabel(app.SessionTab);
            app.FromEditFieldLabel.Text = 'From';
            app.SleepTimeH = uieditfield(app.SessionTab, 'numeric');
            app.SleepTimeH.Limits = [0 23];
            app.SleepTimeH.ValueDisplayFormat = '%.0f';
            app.Label = uilabel(app.SessionTab);
            app.Label.Text = ':';
            app.SleepTMin = uieditfield(app.SessionTab, 'numeric');
            app.SleepTMin.Limits = [0 59];
            app.SleepTMin.ValueDisplayFormat = '%.0f';
            app.ForEditFieldLabel = uilabel(app.SessionTab);
            app.ForEditFieldLabel.Text = 'For';
            app.SleepDur = uieditfield(app.SessionTab, 'numeric');
            app.SleepDur.Limits = [0 16];
            app.SleepDur.ValueDisplayFormat = '%.0f';
            app.hrsLabel = uilabel(app.SessionTab);
            app.hrsLabel.Text = 'hrs';
            app.SounddetectionTab = uitab(app.ParametersTab);
            app.SounddetectionTab.Title = 'Sound detection';
            app.SoundCardLabel = uilabel(app.SounddetectionTab);
            app.SoundCardLabel.Text = 'Sound Card';
            app.ChannelListBoxLabel = uilabel(app.SounddetectionTab);
            app.ChannelListBoxLabel.Text = 'Channel';
            app.SoundDetecChannel = uilistbox(app.SounddetectionTab);
            app.SoundDetecChannel.Items = {'1', '2', '3', '4'};
            app.SoundDetecChannel.Multiselect = 'on';
            app.SoundDetecChannel.Enable = 'off';
            app.SoundDetecChannel.Value = {'1'};
            app.TriggerthresholdsLabel = uilabel(app.SounddetectionTab);
            app.TriggerthresholdsLabel.Text = 'Trigger thresholds';
            app.SoundbufferdurationsecSpinnerLabel = uilabel(app.SounddetectionTab);
            app.SoundbufferdurationsecSpinnerLabel.Text = 'Sound buffer duration (sec)';
            app.SoundbufferdurationsecSpinner = uispinner(app.SounddetectionTab);
            app.SoundbufferdurationsecSpinner.Limits = [0 60];
            app.SoundbufferdurationsecSpinner.Enable = 'off';
            app.SoundbufferdurationsecSpinner.Value = 1;
            app.AmplitudedBEditFieldLabel = uilabel(app.SounddetectionTab);
            app.AmplitudedBEditFieldLabel.Text = 'Amplitude (dB)';
            app.AmplitudedBEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.AmplitudedBEditField.Limits = [-50 0];
            app.AmplitudedBEditField.Value = -35;
            app.HighpassfilterkHzLabel = uilabel(app.SounddetectionTab);
            app.HighpassfilterkHzLabel.Text = 'High pass filter                     kHz';
            app.HighpassfilterkHzEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.HighpassfilterkHzEditField.Limits = [0 Inf];
            app.HighpassfilterkHzEditField.Value = 20;
            app.RMSEditFieldLabel = uilabel(app.SounddetectionTab);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.RMSEditField.Limits = [0 Inf];
            app.RMSEditField.Value = 0.005;
            app.FiledurationminSpinnerLabel = uilabel(app.SounddetectionTab);
            app.FiledurationminSpinnerLabel.Text = 'File duration (min)';
            app.FiledurationminSpinner = uispinner(app.SounddetectionTab);
            app.FiledurationminSpinner.Limits = [1 30];
            app.FiledurationminSpinner.ValueDisplayFormat = '%.0f';
            app.FiledurationminSpinner.Editable = 'off';
            app.FiledurationminSpinner.Enable = 'off';
            app.FiledurationminSpinner.Value = 10;
            app.LowpassfilterkHzEditFieldLabel = uilabel(app.SounddetectionTab);
            app.LowpassfilterkHzEditFieldLabel.Text = 'Low pass filter                     kHz';
            app.LowpassfilterkHzEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.LowpassfilterkHzEditField.Limits = [0 Inf];
            app.LowpassfilterkHzEditField.Value = 90;
            app.LowPassCheckBox = uicheckbox(app.SounddetectionTab);
            app.LowPassCheckBox.Text = '';
            app.HighPassCheckBox = uicheckbox(app.SounddetectionTab);
            app.HighPassCheckBox.Text = '';
            app.HighPassCheckBox.Value = true;
            app.MotuGaindBEditFieldLabel = uilabel(app.SounddetectionTab);
            app.MotuGaindBEditFieldLabel.Text = 'Motu Gain (dB)';
            app.MotuGaindBEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.MotuGaindBEditField.Value = 10;
            app.CurrentcallsdetectedEditFieldLabel = uilabel(app.SounddetectionTab);
            app.CurrentcallsdetectedEditFieldLabel.Text = 'Current # calls detected';
            app.CurrentcallsdetectedEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.CurrentcallsdetectedEditField.Enable = 'off';
            app.DurationmsDropDownLabel = uilabel(app.SounddetectionTab);
            app.DurationmsDropDownLabel.Text = 'Duration (ms)';
            app.DurationmsDropDown = uidropdown(app.SounddetectionTab);
            app.PlaybackTab = uitab(app.ParametersTab);
            app.PlaybackTab.Title = 'Playback';
            app.SoundCardLabel_2 = uilabel(app.PlaybackTab);
            app.SoundCardLabel_2.Text = 'Sound Card';
            app.ChannelListBoxLabel_2 = uilabel(app.PlaybackTab);
            app.ChannelListBoxLabel_2.Text = 'Channel';
            app.PlaybackChannel = uilistbox(app.PlaybackTab);
            app.PlaybackChannel.Items = {'Main Out 1', 'Main Out 2', 'Analog 1', 'Analog 2'};
            app.PlaybackChannel.Multiselect = 'on';
            app.PlaybackChannel.Enable = 'off';
            app.PlaybackChannel.Value = {'Main Out 1'};
            app.SelectListBoxLabel = uilabel(app.PlaybackTab);
            app.SelectListBoxLabel.Text = 'Select';
            app.PlayListBox = uilistbox(app.PlaybackTab);
            app.PlayListBox.Multiselect = 'on';
            app.PlayListBox.Value = {'Item 1'};
            app.SoundfilesLabel = uilabel(app.PlaybackTab);
            app.SoundfilesLabel.Text = 'Sound files';
            app.UpdatefilelistButton = uibutton(app.PlaybackTab, 'push');
            app.UpdatefilelistButton.ButtonPushedFcn = createCallbackFcn(app, @update_file_list_pushed, true);
            app.UpdatefilelistButton.Text = 'Update file list';
            app.RepetitionEditFieldLabel = uilabel(app.PlaybackTab);
            app.RepetitionEditFieldLabel.Text = 'Repetition';
            app.RepetitionEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.RepetitionEditField.Limits = [1 Inf];
            app.RepetitionEditField.Value = 15;
            app.MinIntercallintervalsEditFieldLabel = uilabel(app.PlaybackTab);
            app.MinIntercallintervalsEditFieldLabel.Text = 'Min Inter-call interval (s)';
            app.MinIntercallintervalsEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.MinIntercallintervalsEditField.Limits = [1 Inf];
            app.MinIntercallintervalsEditField.Value = 1;
            app.MaxIntercallintervalsEditFieldLabel = uilabel(app.PlaybackTab);
            app.MaxIntercallintervalsEditFieldLabel.Text = 'Max Inter-call interval (s)';
            app.MaxIntercallintervalsEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.MaxIntercallintervalsEditField.Limits = [1 Inf];
            app.MaxIntercallintervalsEditField.Value = 5;
            app.CalibratePlayBackGainButton = uibutton(app.PlaybackTab, 'push');
            app.CalibratePlayBackGainButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratePlayBackGainButtonPushed, true);
            app.CalibratePlayBackGainButton.Text = 'Calibrate PlayBack Gain';
            app.CurrentRepEditFieldLabel = uilabel(app.PlaybackTab);
            app.CurrentRepEditFieldLabel.Text = 'Current Rep #';
            app.CurrentRepEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.CurrentRepEditField.Editable = 'off';
            app.CurrentRepEditField.Enable = 'off';
            app.PlayingEditFieldLabel = uilabel(app.PlaybackTab);
            app.PlayingEditFieldLabel.Text = 'Playing:';
            app.PlayingEditField = uieditfield(app.PlaybackTab, 'text');
            app.PlayingEditField.Editable = 'off';
            app.PlayingEditField.Enable = 'off';
            app.CompensationCheckBox = uicheckbox(app.PlaybackTab);
            app.CompensationCheckBox.Text = 'Compensation';
            app.PlaybackTaskparametersLabel = uilabel(app.PlaybackTab);
            app.PlaybackTaskparametersLabel.Text = 'Playback Task parameters';
            app.RewardTab = uitab(app.ParametersTab);
            app.RewardTab.Title = 'Reward';
            app.MotorspeedSpinnerLabel = uilabel(app.RewardTab);
            app.MotorspeedSpinnerLabel.Text = 'Motor speed';
            app.MotorspeedSpinner = uispinner(app.RewardTab);
            app.MotorspeedSpinner.Step = 0.1;
            app.MotorspeedSpinner.Limits = [-1 1];
            app.MotorspeedSpinner.Value = 1;
            app.MaxrewardsEditFieldLabel = uilabel(app.RewardTab);
            app.MaxrewardsEditFieldLabel.Text = 'Max # rewards';
            app.MaxrewardsEditField = uieditfield(app.RewardTab, 'numeric');
            app.MaxrewardsEditField.Limits = [0 Inf];
            app.MaxrewardsEditField.ValueDisplayFormat = '%.0f';
            app.MaxrewardsEditField.Value = 180;
            app.RewarddurationsEditFieldLabel = uilabel(app.RewardTab);
            app.RewarddurationsEditFieldLabel.Text = 'Reward duration (s)';
            app.RewarddurationsEditField = uieditfield(app.RewardTab, 'numeric');
            app.RewarddurationsEditField.Limits = [0 2];
            app.RewarddurationsEditField.Value = 0.3;
            app.TimedelaytoretrieverewardsEditFieldLabel = uilabel(app.RewardTab);
            app.TimedelaytoretrieverewardsEditFieldLabel.Text = 'Time delay to retrieve reward (s)';
            app.TimedelaytoretrieverewardsEditField = uieditfield(app.RewardTab, 'numeric');
            app.TimedelaytoretrieverewardsEditField.Limits = [0 30];
            app.TimedelaytoretrieverewardsEditField.Value = 20;
            app.CurrentrewardsdeliveredEditFieldLabel = uilabel(app.RewardTab);
            app.CurrentrewardsdeliveredEditFieldLabel.Text = 'Current # rewards delivered';
            app.CurrentrewardsdeliveredEditField = uieditfield(app.RewardTab, 'numeric');
            app.CurrentrewardsdeliveredEditField.Enable = 'off';
            app.LEDCueonDropDownLabel = uilabel(app.RewardTab);
            app.LEDCueonDropDownLabel.Text = 'LED Cue on';
            app.LEDCueonDropDown = uidropdown(app.RewardTab);
            app.LEDCueonDropDown.Items = {'Until reward or delay reached', 'For max 1s', 'For max 2s', 'For max 5s'};
            app.LEDCueonDropDown.Value = 'Until reward or delay reached';
            app.Delay2retrieverewardStrictFlexDropDown = uidropdown(app.RewardTab);
            app.Delay2retrieverewardStrictFlexDropDown.Items = {'Flexible', 'Strict'};
            app.Delay2retrieverewardStrictFlexDropDown.Value = 'Strict';
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditFieldLabel = uilabel(app.RewardTab);
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditFieldLabel.Text = {'Time Delay to Vocalize (s)'; '(for task Input Beam + Voc)'};
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField = uieditfield(app.RewardTab, 'numeric');
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Limits = [0 Inf];
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Value = 5;
            app.RewardPlayBackCheckBox = uicheckbox(app.RewardTab);
            app.RewardPlayBackCheckBox.Text = 'Reward PlayBack';
            app.PauseafterrewardsLabel = uilabel(app.RewardTab);
            app.PauseafterrewardsLabel.Text = {'Pause'; 'after reward (s)'};
            app.PauseafterrewardsEditField = uieditfield(app.RewardTab, 'numeric');
            app.PauseafterrewardsEditField.Limits = [0 Inf];
            app.CurrentBeamBreakdetectedEditFieldLabel = uilabel(app.RewardTab);
            app.CurrentBeamBreakdetectedEditFieldLabel.Text = 'Current # Beam Break detected';
            app.CurrentBeamBreakdetectedEditField = uieditfield(app.RewardTab, 'numeric');
            app.CurrentBeamBreakdetectedEditField.Enable = 'off';
            app.PausebeforerewardsEditFieldLabel = uilabel(app.RewardTab);
            app.PausebeforerewardsEditFieldLabel.Text = {'Pause'; 'before reward (s)'};
            app.PausebeforerewardsEditField = uieditfield(app.RewardTab, 'numeric');
            app.PausebeforerewardsEditField.Limits = [0 Inf];
            app.JitterInterval10EditFieldLabel = uilabel(app.RewardTab);
            app.JitterInterval10EditFieldLabel.Text = 'Jitter Interval (+/- 10)';
            app.JitterIntervalEditField = uieditfield(app.RewardTab, 'numeric');
            app.JitterIntervalEditField.Value = 120;
            app.EatTimeHEditFieldLabel = uilabel(app.RewardTab);
            app.EatTimeHEditFieldLabel.Text = 'EatTime(H)';
            app.EatTimeHEditField = uieditfield(app.RewardTab, 'numeric');
            app.EatTimeHEditField.Value = 10;
            app.EatTimeAEditFieldLabel = uilabel(app.RewardTab);
            app.EatTimeAEditFieldLabel.Text = 'EatTime(A)';
            app.EatTimeAEditField = uieditfield(app.RewardTab, 'numeric');
            app.EatTimeAEditField.Value = 15;
            app.PunishmentTab = uitab(app.ParametersTab);
            app.PunishmentTab.Title = 'Punishment';
            app.DurationsecEditFieldLabel = uilabel(app.PunishmentTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.TimeOutDuration = uieditfield(app.PunishmentTab, 'numeric');
            app.TimeOutDuration.Limits = [0 Inf];
            app.TimeOutDuration.Value = 120;
            app.TimeoutforignoringthecueCheckBox = uicheckbox(app.PunishmentTab);
            app.TimeoutforignoringthecueCheckBox.Text = 'Time out for ignoring the cue';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditFieldLabel = uilabel(app.PunishmentTab);
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditFieldLabel.Text = {'# unsuccessful reward retrieval'; 'to trigger Dunce cap'};
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField = uieditfield(app.PunishmentTab, 'numeric');
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Limits = [0 Inf];
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.ValueDisplayFormat = '%.0f';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value = 10;
            app.DuncecapforignoringthecueCheckBox = uicheckbox(app.PunishmentTab);
            app.DuncecapforignoringthecueCheckBox.Text = 'Dunce cap for ignoring the cue';
            app.DurationsecEditFieldLabel_2 = uilabel(app.PunishmentTab);
            app.DurationsecEditFieldLabel_2.Text = 'Duration (sec)';
            app.DunceCapDuration = uieditfield(app.PunishmentTab, 'numeric');
            app.DunceCapDuration.Limits = [0 Inf];
            app.DunceCapDuration.Value = 600;
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditFieldLabel = uilabel(app.PunishmentTab);
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditFieldLabel.Text = {'Current # consecutive unsuccessful reward retrieval'; ''};
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField = uieditfield(app.PunishmentTab, 'numeric');
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField.Limits = [0 Inf];
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField.ValueDisplayFormat = '%.0f';
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField.Enable = 'off';
            app.TotalunsuccessfulrewardretrievalEditFieldLabel = uilabel(app.PunishmentTab);
            app.TotalunsuccessfulrewardretrievalEditFieldLabel.Text = {'Total # unsuccessful reward retrieval'; ''};
            app.TotalunsuccessfulrewardretrievalEditField = uieditfield(app.PunishmentTab, 'numeric');
            app.TotalunsuccessfulrewardretrievalEditField.Limits = [0 Inf];
            app.TotalunsuccessfulrewardretrievalEditField.ValueDisplayFormat = '%.0f';
            app.TotalunsuccessfulrewardretrievalEditField.Enable = 'off';
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField_2Label = uilabel(app.PunishmentTab);
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField_2Label.Text = 'Current # consecutive unsuccessful reward retrieval';
            app.CurrentconsecutiveunsuccessfulrewardretrievalEditField_2 = uieditfield(app.PunishmentTab, 'numeric');
            app.ResultsTab = uitab(app.ParametersTab);
            app.ResultsTab.Title = 'Results';
            app.SelectsessionLabel = uilabel(app.ResultsTab);
            app.SelectsessionLabel.Text = 'Select session';
            app.SelectDataDropDown = uidropdown(app.ResultsTab);
            app.UpdateDataListButton = uibutton(app.ResultsTab, 'push');
            app.UpdateDataListButton.ButtonPushedFcn = createCallbackFcn(app, @update_data_list_pushed, true);
            app.UpdateDataListButton.Text = 'Update Data List';
            app.UpdategraphButton = uibutton(app.ResultsTab, 'push');
            app.UpdategraphButton.ButtonPushedFcn = createCallbackFcn(app, @update_graph_pushed, true);
            app.UpdategraphButton.Text = 'Update graph';
            app.BatOneReLabel = uilabel(app.ResultsTab);
            app.BatOneReLabel.Text = 'Back Re';
            app.BatTwoReLabel = uilabel(app.ResultsTab);
            app.BatTwoReLabel.Text = 'Front Re';
            app.BatOneVocLabel = uilabel(app.ResultsTab);
            app.BatOneVocLabel.Text = 'Back Voc';
            app.BatTwoVocLabel = uilabel(app.ResultsTab);
            app.BatTwoVocLabel.Text = 'Front Voc';
            app.XminEditFieldLabel = uilabel(app.ResultsTab);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.ResultsTab, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @update_graph_xlim, true);
            app.XmaxEditFieldLabel = uilabel(app.ResultsTab);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.ResultsTab, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @update_graph_xlim, true);
            app.SoundtimeDropDownLabel = uilabel(app.ResultsTab);
            app.SoundtimeDropDownLabel.Text = 'Sound time';
            app.SoundtimeDropDown = uidropdown(app.ResultsTab);
            app.SoundtimeDropDown.ValueChangedFcn = createCallbackFcn(app, @update_graph_section, true);
            app.UIAxes = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Sound Presssure')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.Results2Tab = uitab(app.ParametersTab);
            app.Results2Tab.Title = 'Results2';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.BoxmanualcontrolPanel = uipanel(app.UIFigure);
            app.BoxmanualcontrolPanel.Title = 'Box manual control';
            app.Reset1Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Reset1Button.ButtonPushedFcn = createCallbackFcn(app, @reset1_button_pushed, true);
            app.Reset1Button.Text = 'Reset #1';
            app.FwdButton1 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.FwdButton1.ButtonPushedFcn = createCallbackFcn(app, @fwd1_button_pushed, true);
            app.FwdButton1.Text = '+';
            app.BwdButton1 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.BwdButton1.ButtonPushedFcn = createCallbackFcn(app, @bwd1_button_pushed, true);
            app.BwdButton1.Text = '-';
            app.MotorsLabel = uilabel(app.BoxmanualcontrolPanel);
            app.MotorsLabel.Text = 'Motors';
            app.LEDsLabel = uilabel(app.BoxmanualcontrolPanel);
            app.LEDsLabel.Text = 'LEDs';
            app.Cue1Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Cue1Button.ButtonPushedFcn = createCallbackFcn(app, @cue1_LED_flash, true);
            app.Cue1Button.Text = 'Cue #1';
            app.Cue2Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Cue2Button.ButtonPushedFcn = createCallbackFcn(app, @cue2_LED_flash, true);
            app.Cue2Button.Text = 'Cue #2';
            app.SpeakerLabel = uilabel(app.BoxmanualcontrolPanel);
            app.SpeakerLabel.Text = 'Speaker';
            app.MicLabel = uilabel(app.BoxmanualcontrolPanel);
            app.MicLabel.Text = 'Mic';
            app.PlayButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_button_pushed, true);
            app.PlayButton.Text = 'Play';
            app.Detect = uibutton(app.BoxmanualcontrolPanel, 'state');
            app.Detect.ValueChangedFcn = createCallbackFcn(app, @detect_sound_mic, true);
            app.Detect.Text = 'Detect';
            app.MicLamp = uilamp(app.BoxmanualcontrolPanel);
            app.BeamBreakersLabel = uilabel(app.BoxmanualcontrolPanel);
            app.BeamBreakersLabel.Text = 'Beam Breakers';
            app.TestFoodBatTwo = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestFoodBatTwo.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestFoodBatTwo.Text = 'Test Bat 2';
            app.TestFoodBatOne = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestFoodBatOne.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestFoodBatOne.Text = 'Test Bat 1';
            app.TestFoodBatThree = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestFoodBatThree.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestFoodBatThree.Text = 'Test Bat 3';
            app.TestFoodBatFour = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestFoodBatFour.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestFoodBatFour.Text = 'Test Bat 4';
            app.BBLamp = uilamp(app.BoxmanualcontrolPanel);
            app.DurationsEditFieldLabel = uilabel(app.BoxmanualcontrolPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.BoxmanualcontrolPanel, 'numeric');
            app.DurationsEditField.Limits = [0 Inf];
            app.DurationsEditField.Value = 10;
            app.Cue3Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Cue3Button.ButtonPushedFcn = createCallbackFcn(app, @cue3_LED_flash, true);
            app.Cue3Button.Text = 'Cue #3';
            app.Cue4Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Cue4Button.ButtonPushedFcn = createCallbackFcn(app, @cue4_LED_flash, true);
            app.Cue4Button.Text = 'Cue #4';
            app.Reset2Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Reset2Button.ButtonPushedFcn = createCallbackFcn(app, @reset2_button_pushed, true);
            app.Reset2Button.Text = 'Reset #2';
            app.FwdButton2 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.FwdButton2.ButtonPushedFcn = createCallbackFcn(app, @fwd2_button_pushed, true);
            app.FwdButton2.Text = '+';
            app.BwdButton2 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.BwdButton2.ButtonPushedFcn = createCallbackFcn(app, @bwd2_button_pushed, true);
            app.BwdButton2.Text = '-';
            app.Reset3Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Reset3Button.ButtonPushedFcn = createCallbackFcn(app, @reset3_button_pushed, true);
            app.Reset3Button.Text = 'Reset #3';
            app.FwdButton3 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.FwdButton3.ButtonPushedFcn = createCallbackFcn(app, @fwd3_button_pushed, true);
            app.FwdButton3.Text = '+';
            app.BwdButton3 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.BwdButton3.ButtonPushedFcn = createCallbackFcn(app, @bwd3_button_pushed, true);
            app.BwdButton3.Text = '-';
            app.Reset4Button = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.Reset4Button.ButtonPushedFcn = createCallbackFcn(app, @reset4_button_pushed, true);
            app.Reset4Button.Text = 'Reset #4';
            app.FwdButton4 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.FwdButton4.ButtonPushedFcn = createCallbackFcn(app, @fwd4_button_pushed, true);
            app.FwdButton4.Text = '+';
            app.BwdButton4 = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.BwdButton4.ButtonPushedFcn = createCallbackFcn(app, @bwd4_button_pushed, true);
            app.BwdButton4.Text = '-';
            app.TestHand = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestHand.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestHand.Text = 'Test Hand';
            app.BoxIDLabel = uilabel(app.UIFigure);
            app.BoxIDLabel.Text = 'Box ID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HLR_humanbatexp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end